classdef ezActi2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActigraphycontrolpanelUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SinglefileMenu                  matlab.ui.container.Menu
        ListfileMenu                    matlab.ui.container.Menu
        ImportWizardMenu                matlab.ui.container.Menu
        OptionMenu                      matlab.ui.container.Menu
        AdvancedMenu                    matlab.ui.container.Menu
        DetrendedfluctuationanalysisDFAMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutezActiMenu                 matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        AutoButton                      matlab.ui.control.Button
        FlipButton                      matlab.ui.control.Button
        InterpButton                    matlab.ui.control.Button
        CropButton                      matlab.ui.control.Button
        WriteButton                     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        DoButton                        matlab.ui.control.Button
        previewUITable                  matlab.ui.control.Table
        PlotButton                      matlab.ui.control.Button
        Status                          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CircadianTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SummaryreportCheckBox           matlab.ui.control.CheckBox
        hourLabel_3                     matlab.ui.control.Label
        hourLabel_2                     matlab.ui.control.Label
        hourLabel                       matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        TargetcomponentEditField        matlab.ui.control.NumericEditField
        TargetcomponentLabel            matlab.ui.control.Label
        HarmonicsEditField              matlab.ui.control.EditField
        HarmonicsLabel                  matlab.ui.control.Label
        MinlengthEditField              matlab.ui.control.EditField
        MinlengthEditFieldLabel         matlab.ui.control.Label
        NapTab                          matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        SummaryreportCheckBox_Nap       matlab.ui.control.CheckBox
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        DaytimeStartEditField           matlab.ui.control.EditField
        DaytimeEditFieldLabel           matlab.ui.control.Label
        DaytimeEndEditField             matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        SleepTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SummaryreportCheckBox_Sleep     matlab.ui.control.CheckBox
        ModeDropDown_Sleep              matlab.ui.control.DropDown
        ModeDropDown_2Label             matlab.ui.control.Label
        SleepStartEditField             matlab.ui.control.EditField
        TimerestrictionLabel            matlab.ui.control.Label
        SleepEndEditField               matlab.ui.control.EditField
        Label_9                         matlab.ui.control.Label
        GapTab                          matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        minLabel_2                      matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        ManAutoSwitch                   matlab.ui.control.Switch
        Label_3                         matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        EndEditFieldLabel               matlab.ui.control.Label
        ThreEditField                   matlab.ui.control.EditField
        ThreEditFieldLabel              matlab.ui.control.Label
        MinDurEditField                 matlab.ui.control.EditField
        MinDurEditFieldLabel            matlab.ui.control.Label
        threUnitDropDown                matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        MergeifEditField                matlab.ui.control.EditField
        MergeifEditFieldLabel           matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        circShowArea                    matlab.ui.control.TextArea
        Label_7                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = public, Hidden = true)
        % configuration
        config = struct('RunMode', {'Gap'}, ...
            'ExistedFlag', 0, ...
            'ExistedExtension', {''}, ...
            'Gap', 0, ...
            'GapExtension', {''}, ...
            'Actogram', 0, ...
            'PolarClock', 0, ...
            'SummaryReport', 0, ...
            'Figures', 0, ...
            'FigureExt', '.jgp', ...
            'TimeParserMode', {'None'}, ...
            'TimeParserProperties', ...
            struct('SameDateTime', '', ...
            'DateTimeFileLocation', '', ...
            'DatetimeInd', 1, ...
            'DatetimeFormat', 'yyyyMMddHHmmss'), ...
            'EpochParserMode', {'None'}, ...
            'EpochParserProperties', ...
            struct('SameEpoch', 15, ...
            'EpochFileLocation', '', ...
            'EpochInd', 1), ...
            'FileNameSeparator', '_')
        
        % data access
        filePath                        % data dir
        fileList                        % all list files
        importedEpi = 0
        importedEpiSuffix
        importedGap = 0                 % to always carry gaps over to ease the process of other modes
        importedGapSuffix               % if Mode = 'Gap', they are the same to importedEpi and importedEpiSuffix
        
        startTimeFileInfo
        
        % gap specific
        gap = struct('matchFile', '', ...
            'parameter', struct('method', 'Manual', 'startIndex', nan, 'endIndex', nan, 'threshold', [], 'thresholdUnit', 'Min', 'minDuration', [], 'minDistance', []), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 0, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over
        
        % circadian specific
        circadian = struct('matchFile', '', ...
            'parameter', struct('method', 'Cosine curve fit', 'target', 24, 'harmonic', [], 'minLength', 7*24), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...              % reserved field
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over
        
        % nap specific
        nap = struct('matchFile', '', ...
            'parameter', struct('mode', 'Proportional integrating', 'modeParameter', struct('P', 0.001, 'V', [106 54 58 76 230 74 67]./2, 'C', 0), 'startTime', '9:00', 'endTime', '19:00'), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over
        
        % sleep specific
        sleep = struct('matchFile', '', ...
            'parameter', struct('mode', 'Proportional integrating', 'modeParameter', struct('P', 0.001, 'V', [106 54 58 76 230 74 67]./2, 'C', 0), 'startTime', '21:00', 'endTime', '7:00'), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []));                % 5/14/2021 supports gap carry over
        
        % plot spec
        actigraphyApp
        yrange
        
        % runtime
        startIndex
        
        % switch
        dataDefined  = 0
        timeDispMode = 0 % time display mode: 0-disabled, 1-from file, 2-from file name, 3-all the same from input
        dataShown    = 0
    end
    
    properties (Access = public, Hidden = true)
        % ezActi2 data
        % data2 should be an acti object
        % however, acti.Sleep and acti.Gap will not be set
        % when loaded, although sometimes predefined results
        % are to be loaded when config'ed
        % this is to dissociate this to the sleep/gap struct
        % of the ezActi2 object
        % so temporally, still use ezActi plot scheme (the @acti.plot
        % need some updates later)
        data2
        dataLoaded = 0
        
        message = ''
        
        % data parameter
        x
        data
        dataImputed
        epoch = 15
        startTime
        
        % runtime
        mode
        currentFileName
        currentFileNameNoExt
        currentFileNameFmt
        
        % figure handlers
        gSig
        gImpute
        
        % gap specific
        gapImputationCrit
        gapImputationWindow
        gapImputationMethod = 'Centered mean'
        gapImputationSave = 1
        
        % switch
        timeSet = 0
    end
    
    methods (Access = private)
        function statusFeed(app, msg, varargin)
            color = 'k';
            if nargin == 3
                if ~ischar(varargin{1}) && numel(varargin{1}) == 3
                    color = varargin{1};
                else
                    switch varargin{1}
                        case 'warn'
                            color = [1 .325 .286];
                        case {'error', 'r'}
                            color = 'r';
                    end
                end
            end
            if nargin == 4
                oldStatus = string(varargin{2});
            else
                oldStatus = "";
            end
            app.Status.Text      = oldStatus + msg;
            app.Status.FontColor = color;
        end
        
        function enable(app, event)
            set(event, 'Enable', 'on');
        end
        
        function disable(app, event)
            set(event, 'Enable', 'off');
        end
        
        function resetGapControl(app, event)
            app.StartEditField.Value  = '';
            app.EndEditField.Value    = '';
            app.previewUITable.Data   = [];
            app.gap.data = [];
        end
        
        function resetCircadianControl(app, event)
            app.circShowArea.Value  = '';
        end
        
        function resetNapControl(app, event)
            % do nothing
        end
        
        function resetSleepControl(app, event)
            % do nothing
        end
        
        function dataShow(app)
            app.yrange = [min(app.data(:, end)) max(app.data(:, end))];
            app.yrange = [app.yrange(1) - .1*diff(app.yrange), app.yrange(2) + .1*diff(app.yrange)];
            
            % signal end bar
            plot(app.actigraphyApp.actiAxis, [app.x(end), app.x(end)], [-1e6 1e6], 'LineWidth', 3, 'Color', 'c');
            set(app.actigraphyApp.actiAxis, 'NextPlot', 'add');
            
            % plot signal
            app.gSig    = plot(app.actigraphyApp.actiAxis, app.x, app.data(:, end), 'Color', 'k');
            
            % plot day seperator
            PointPerDay = 24*60*60 / app.epoch;
            TotalDays   = ceil(length(app.x) / PointPerDay);
            StartBorder = (1:TotalDays-1) .* PointPerDay + 1;
            Seperator   = repmat([-1e6; 1e6], 1, length(StartBorder));
            plot(app.actigraphyApp.actiAxis, [StartBorder; StartBorder], Seperator, 'Color', [.15 .15 .65], 'LineStyle', '--', 'LineWidth', 2);
            xlabel(app.actigraphyApp.actiAxis, 'Points');
            
            % range set
            if app.yrange(2) > app.yrange(1) % to avoid nan
                app.actigraphyApp.actiAxis.YLim    = app.yrange;
            end
            app.actigraphyApp.actiAxis.XLim    = [0 length(app.x)];
            app.actigraphyApp.shallowAxis.XLim = [0 length(app.x)];
            zoom('reset');
            
            % display time
            switch app.timeDispMode
                case 1
                    [~, fileID] = fileparts(app.currentFileName);
                    if any(string(app.startTimeFileInfo.FileID) == string(fileID))
                        app.timeSet   = 1;
                        app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
                    else
                        app.timeSet   = 0;
                    end
                case 2
                    nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                    app.startTime = datetime(...
                        nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
                        'InputFormat', ...
                        app.config.TimeParserProperties.DatetimeFormat);
                    app.timeSet   = 1;
            end
            
            if app.timeSet
                app.actigraphyApp.actiAxis.Box        = 'off';
                app.actigraphyApp.shallowAxis.Visible = 'on';
                
                peerXTick = app.actigraphyApp.actiAxis.XTick;
                xTick     = app.startTime + seconds(app.epoch).*peerXTick - app.x(1).*seconds(app.epoch);
                app.actigraphyApp.shallowAxis.XTick = peerXTick;
                app.actigraphyApp.shallowAxis.XTickLabel = datestr(xTick, 'dd-mmm-yyyy HH:MM');
                app.actigraphyApp.shallowAxis.XTickLabelRotation = 10;
            else
                app.actigraphyApp.shallowAxis.Visible = 'off';
                app.actigraphyApp.actiAxis.Box        = 'on';
            end
            
            % show toolbar
            app.actigraphyApp.shallowAxis.Toolbar.Visible = 'off';
            app.actigraphyApp.actiAxis.Toolbar.Visible    = 'on';
            
            %             function [datetimestr, format] = parsetime(str, format)
            %                 % PARSETIME parse date time from string using format
            %                 %
            %                 % $Author: Peng Li
            %                 % $Date:   Apr. 5, 2021
            %                 %
            %
            %                 ind = strfind(format, '*');
            %
            %                 if numel(ind) == 1
            %                     if ind == 1
            %                         datetimestr = str(end-length(format)+1+1:end);
            %                     else
            %                         datetimestr = str(1:ind-1);
            %                     end
            %                 else
            %                     datetimestr = str(setxor(1:length(str), ind));
            %                 end
            %
            %                 format = format(setxor(1:length(format), ind));
            %             end
        end
        
        function addItem(app, event, item)
            iG = length(app.(lower(app.mode)).handle) + 1;
            app.(lower(app.mode)).handle(iG) = patch(app.actigraphyApp.actiAxis, 'XData', ...
                [item(1) item(1) item(2) item(2)], ...
                'YData', [app.yrange(1) app.yrange(2) app.yrange(2) app.yrange(1)], ...
                'FaceColor', 'm', 'EdgeColor', 'm', 'FaceAlpha', 0.2);
            app.(lower(app.mode)).text(iG) = text(item(1), app.yrange(2) - ((iG-1)./size(app.(lower(app.mode)).data, 1))*diff(app.yrange), ...
                [app.mode ': # ' num2str(iG)], 'Color', 'm');
            
            % switch layers
            child = app.actigraphyApp.actiAxis.Children;
            child = [child(end); child(1:end-1)];
            app.actigraphyApp.actiAxis.Children = child;
        end
        
        function updateItem(app)
            epi    = app.(lower(app.mode)).data;
            Yrange = app.yrange;
            
            % update plot
            if ~isempty(app.(lower(app.mode)).handle)
                delete(app.(lower(app.mode)).handle);
                app.(lower(app.mode)).handle = [];
            end
            
            if ~isempty(app.(lower(app.mode)).text)
                delete(app.(lower(app.mode)).text);
                app.(lower(app.mode)).text = [];
            end
            
            if ~strcmpi(app.mode, 'circadian')
                % update Gap Table on Face
                app.previewUITable.Data = epi;
                
                for iG = 1:size(epi, 1)
                    app.(lower(app.mode)).handle(iG) = patch('Parent', app.actigraphyApp.actiAxis, 'XData', [epi(iG, 1) epi(iG, 1) epi(iG, 2) epi(iG, 2)], ...
                        'YData', [Yrange(1) Yrange(2) Yrange(2) Yrange(1)], 'FaceColor', 'm', 'EdgeColor', 'm', 'FaceAlpha', 0.2);
                    app.(lower(app.mode)).text(iG) = text(app.actigraphyApp.actiAxis, epi(iG, 1), Yrange(2) - ((iG-1)./size(epi, 1))*diff(Yrange), [app.mode ': # ' num2str(iG)], 'Color', 'm');
                end
                
                
                % switch layers
                child = app.actigraphyApp.actiAxis.Children;
                child = [child(end-length(app.gImpute):end); child(1:end-length(app.gImpute)-1)];
                app.actigraphyApp.actiAxis.Children = child;
            else
                app.(lower(app.mode)).handle = plot(app.actigraphyApp.actiAxis, app.x, epi, 'r', 'LineWidth', 2);
            end
            
            drawnow;
        end
        
        function gapImputation(app)
            toImputeGapIndex = find(app.(lower(app.mode)).data(:, 2)-app.(lower(app.mode)).data(:, 1) <= app.gapImputationCrit);
            imputationMarker = ones(size(toImputeGapIndex))&1;
            
            if isempty(toImputeGapIndex)
                app.statusFeed('Caution: no gaps meet criteria. No imputation done.', 'warn');
                return;
            end
            
            if ~isempty(app.gImpute)
                delete(app.gImpute);
                app.gImpute = [];
            end
            
            data_ = app.data;
            gaps  = ~gap2Series(app.(lower(app.mode)).data, length(data_));
            data_(gaps) = nan;
            
            iG2 = 1;
            for iG = 1:length(toImputeGapIndex)
                cx = app.x(app.(lower(app.mode)).data(toImputeGapIndex(iG), 1):app.(lower(app.mode)).data(toImputeGapIndex(iG), 2));
                if cx(1) == 1 || cx(2) == length(app.x)
                    imputationMarker(iG) = 0&0;
                    continue;
                else
                    imputationWindLen = max(ceil((app.gapImputationWindow - length(cx))/2), 1);
                    extCx = [max(1, cx(1)-imputationWindLen):cx(1)-1 cx(end)+1:min(length(app.x), cx(end)+imputationWindLen)];
                    
                    extCy = [data_(extCx(1):cx(1)-1); data_(cx(end)+1:extCx(end))];
                    
                    switch app.gapImputationMethod
                        case 'Centered mean'
                            app.dataImputed(cx) = nanmean(extCy);
                        case 'Linear interp'
                            app.dataImputed(cx) = interp1(extCx, extCy, cx, 'linear');
                    end
                    
                    app.gImpute(iG2) = plot(app.actigraphyApp.actiAxis, cx, app.dataImputed(cx), ...
                        'Color', 'g', 'LineWidth', 2, 'Marker', '.');
                    iG2 = iG2 + 1;
                end
            end
            
            % delete gaps where imputation is done
            app.(lower(app.mode)).data(toImputeGapIndex(imputationMarker), :) = [];
            app.updateItem;
            app.statusFeed('Data imputed and gaps info updated.');
            
            % save data or not
            if app.gapImputationSave
                savePt = fullfile(app.filePath, 'Imputed');
                if ~(exist(savePt, 'dir') == 7)
                    mkdir(savePt);
                end
                
                fid = fopen(fullfile(savePt, [app.currentFileName(1:end-4) '.imputed']), 'w');
                fprintf(fid, '%f\r', app.dataImputed);
                fclose(fid);
                
                if ~isempty(app.(lower(app.mode)).data)
                    gapPt = fullfile(savePt, 'Gap');
                    if ~(exist(gapPt, 'dir') == 7)
                        mkdir(gapPt);
                    end
                    fid = fopen(fullfile(gapPt, [app.currentFileName(1:end-4) '_imputed.gap']), 'w');
                    fprintf(fid, '%d,%d\r', app.(lower(app.mode)).data');
                    fclose(fid);
                end
            end
            app.statusFeed('Data imputed, gaps info updated, and imputed data saved.');
        end
        
        function doGap(app)
            switch app.gap.parameter.method
                case 'Manual'
                    if isnan(app.gap.parameter.startIndex) || isnan(app.gap.parameter.endIndex)
                        app.statusFeed('Either start or end index invalid!', 'r');
                        return;
                    end
                    
                    if app.gap.parameter.endIndex < app.gap.parameter.startIndex
                        app.statusFeed('Manual define error: start index should be smaller than end index!', 'r');
                        return;
                    end
                    
                    epiRec = [app.gap.parameter.startIndex app.gap.parameter.endIndex];
                    
                    if ~isempty(app.(lower(app.mode)).data) && ~isempty(intersect(app.(lower(app.mode)).data, epiRec, 'rows', 'stable'))
                        app.statusFeed('Caution: already defined episode; not added!', [1 .325 .286]);
                        return;
                    end
                    
                    if epiRec(2) > length(app.data)
                        app.statusFeed('Caution: episode updated. End index larger than data length! Forced to be equal to data length by the app.', 'warn');
                        epiRec(2) = length(app.data);
                    end
                    
                    app.(lower(app.mode)).data = [app.(lower(app.mode)).data; epiRec];
                    
                    [~, ind] = sort(app.(lower(app.mode)).data(:, 1));
                    app.(lower(app.mode)).data = app.(lower(app.mode)).data(ind, :);
                    
                    % update preview
                    app.previewUITable.Data  = app.(lower(app.mode)).data;
                    
                    % add item on plot
                    addItem(app, [], epiRec);
                    
                    % reset detectables
                    app.StartEditField.Value     = '';
                    app.gap.parameter.startIndex = nan;
                    app.EndEditField.Value       = '';
                    app.gap.parameter.endIndex   = nan;
                    
                    % update the order of plot and text
                    % accordingly
                    app.(lower(app.mode)).handle = app.(lower(app.mode)).handle(ind);
                    app.(lower(app.mode)).text   = app.(lower(app.mode)).text(ind);
                    
                    % update text display
                    for iT = 1:length(app.(lower(app.mode)).text)
                        set(app.(lower(app.mode)).text(iT), 'String', [app.mode ': # ' num2str(iT)], 'Color', 'm');
                    end
                    
                    if epiRec(2) == app.gap.parameter.endIndex
                        app.statusFeed('Episode updated.');
                    end
                case 'Auto'
                    if ~(isempty(app.gap.data))
                        delete(app.gap.handle); app.gap.handle = [];
                        delete(app.gap.text); app.gap.text = [];
                        app.gap.data = [];
                    end
                    
                    rec = app.data;
                    
                    switch app.gap.parameter.thresholdUnit
                        case 'Min'
                            gapseries1 = rec <= app.gap.parameter.threshold;
                        case 'Max'
                            gapseries1 = rec >= app.gap.parameter.threshold;
                        case 'Range'
                            gapseries1 = rec <= app.gap.parameter.threshold(1) | rec >= app.gap.parameter.threshold(2);
                        case 'SD'
                            lmean = mean(rec); thre = app.gap.parameter.threshold.*std(rec);
                            gapseries1 = (abs(rec - lmean) >= thre) & 0; % to update
                    end
                    
                    % 2. duration criterion
                    if any(gapseries1)
                        gapTemp = detConstantOne(gapseries1);
                        gapTemp(gapTemp(:, 2) - gapTemp(:, 1) < app.gap.parameter.minDuration*60/app.epoch, :) = [];
                        
                        gapseries2 = ~gap2Series(gapTemp, length(rec));
                        
                        % enable adapting in nan
                        gapseries2 = gapseries2 | isnan(rec);
                        
                        % 3. merge
                        segs = transSegGap(detConstantOne(gapseries2), length(rec));
                        merg = segs(:, 2) - segs(:, 1) <= app.gap.parameter.minDistance*60/app.epoch;
                        segs(merg, :) = [];
                        
                        % auto det will clear all cache gaps
                        if ~ (size(segs, 1) == 1 && all(segs == [1, length(rec)]))
                            gapTemp  = transSegGap(segs, length(rec));
                            gapTemp  = unique(gapTemp, 'rows');
                            [~, ind] = sort(gapTemp(:, 1));
                            app.(lower(app.mode)).data = gapTemp(ind, :);
                        end
                    else
                        app.(lower(app.mode)).data = [];
                    end
                    
                    % 4. update Face
                    app.updateItem;
                    app.statusFeed('Auto detection done!')
            end
        end
        
        function doCircadian(app)
            if length(app.dataImputed)*app.epoch/3600 < app.circadian.parameter.minLength
                app.statusFeed('Caution: min. length not met. Do nothing.', 'warn');
                return;
            end
            
            if ~isempty(app.(lower(app.mode)).handle)
                delete(app.(lower(app.mode)).handle);
                app.(lower(app.mode)).handle = [];
                try delete(app.(lower(app.mode)).text); end
                app.(lower(app.mode)).text = [];
            end
            
            if app.timeSet
                refZeroDegree = datetime(year(app.startTime), month(app.startTime), day(app.startTime), 0, 0, 0);
                tInDegree = hours((app.startTime + seconds(app.epoch).*(1:length(app.dataImputed)) - seconds(app.epoch)) - refZeroDegree)*15;
            else
                tInDegree = hours(seconds(app.epoch).*(0:length(app.dataImputed)-1))*15;
            end
            tInSec = tInDegree * 3600 / 15;
            
            switch app.circadian.parameter.method
                case 'Cosine curve fit'
                    [app.circadian.data, pValue, rSquare, component, verbose] = CosAna(app.dataImputed, tInSec, [app.circadian.parameter.target app.circadian.parameter.harmonic]);
                    
                    if app.timeSet
                        verbose = [verbose sprintf('\n%s\n', 'Using mid-night (12:00am) as 0°')];
                    else
                        verbose = [verbose sprintf('\n%s\n', 'Using start of recording as 0°')];
                    end
                    
                    verbose = [verbose sprintf('\np value: %.6f\n', pValue) sprintf('R squared: %.6f\n', rSquare)];
                    
                    app.circShowArea.Value = verbose;
            end
            
            % show data
            app.(lower(app.mode)).handle = plot(app.actigraphyApp.actiAxis, app.x, app.circadian.data, 'r', 'LineWidth', 2);
            
            % summary
            if app.circadian.report
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end
                
                if ~(exist(fullfile(writePt, 'report.CIRCADIAN'), 'file') == 2)
                    fid = fopen(fullfile(writePt, 'report.CIRCADIAN'), 'w');
                    fprintf(fid, '%s,%s,%s,%s,%s,%s', 'filename', 'mean', 'sd', 'mesor', 'pvalue', 'rsquared');
                    for iH = 1:length(component.FitFreq)
                        fprintf(fid, ',%s,%s,%s', "amplitude"+string(component.FitFreq(iH)), "acrophase"+string(component.FitFreq(iH)), "acrophase"+string(component.FitFreq(iH))+"_in_hour");
                    end
                    fprintf(fid, '\r');
                else
                    fid = fopen(fullfile(writePt, 'report.CIRCADIAN'), 'a');
                end
                
                fprintf(fid, '%s,%f,%f,%f,%f,%f', app.currentFileName, nanmean(app.dataImputed), nanstd(app.dataImputed), component.const, pValue, rSquare);
                for iH = 1:length(component.FitFreq)
                    fprintf(fid, ',%f,%f', component.AC(iH), component.theta(iH));
                    phase_in_hour = component.theta(iH) * 360 / component.FitFreq(iH); % modif. 7.12.2021 instead of theta/15, do theta*360/P to reflect the actual period
                    if phase_in_hour < 0
                        phase_in_hour = component.FitFreq(iH)+phase_in_hour;
                    end
                    fprintf(fid, ',%f', phase_in_hour);
                end
                fprintf(fid, '\r');
                fclose(fid);
                
                app.statusFeed('Circadian component estimated and saved.');
            else
                app.statusFeed('Circadian component estimated.');
            end
        end
        
        function doNap(app)
            if ~(isempty(app.nap.data))
                delete(app.nap.handle); app.nap.handle = [];
                delete(app.nap.text); app.nap.text = [];
                app.nap.data = [];
            end
            
            % daytime mask
            dayMask = doMask2(length(app.data), app.epoch, app.startTime, app.nap.parameter.startTime, app.nap.parameter.endTime);
            
            % sleep detection
            sleepSeries = doSleepDet(app.data, app.epoch, app.nap.parameter.modeParameter.V, app.nap.parameter.modeParameter.P, app.nap.parameter.modeParameter.C, 2);
            
            temp   = sleepSeries & dayMask;
            napEpi = detConstantOne(temp);
            
            % napping: >=5 min otherwise discarded
            if ~isempty(napEpi)
                napEpi(napEpi(:, 2) - napEpi(:, 1) < 5 * 60 / app.epoch, :) = [];
            end
            
            app.nap.data = napEpi;
            
            % update Face
            app.updateItem;
            app.statusFeed('Nap detection done!')
            
            % summary
            if app.nap.report
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end
                
                if ~(exist(fullfile(writePt, 'report.NAP'), 'file') == 2)
                    fid = fopen(fullfile(writePt, 'report.NAP'), 'w');
                    fprintf(fid, '%s,%s,%s,%s,%s\r', 'filename', 'nap_duration_in_min_per_day', 'nap_frequency_per_day', 'nap_timing', 'activity_length');
                else
                    fid = fopen(fullfile(writePt, 'report.NAP'), 'a');
                end
                
                acti_length_in_day = length(app.data) * app.epoch / 3600 / 24;
                
                if ~isempty(app.nap.data)
                    nap_duration_in_min_per_day = sum(app.nap.data(:, 2) - app.nap.data(:, 1) + 1) * app.epoch / 60 / acti_length_in_day;
                    nap_frequency_per_day       = size(app.nap.data, 1) / acti_length_in_day;
                    
                    % to be refined
                    mid_time = floor((app.nap.data(:, 2) - app.nap.data(:, 1))/2) + app.nap.data(:, 1);
                    mid_time_in_time = app.startTime + (mid_time - 1) * app.epoch ./ 3600 ./ 24;
                    mid_time_in_hour = hour(mid_time_in_time);
                    nap_time_table = table(zeros(length(mid_time), 1), zeros(length(mid_time), 1), zeros(length(mid_time), 1), ...
                        'VariableNames', {'before_noon', 'early_afternoon', 'late_afternoon'});
                    for iN = 1:length(mid_time_in_hour)
                        switch mid_time_in_hour(iN)
                            case {9, 10, 11}
                                nap_time_table.before_noon(iN) = 1;
                            case {12, 13, 14, 15}
                                nap_time_table.early_afternoon(iN) = 1;
                            case {16, 17, 18, 19}
                                nap_time_table.late_afternoon(iN) = 1;
                        end
                    end
                    
                    freq = sum(nap_time_table{:, :}) ./ length(mid_time_in_hour);
                    [maxfreq, maxind] = max(freq);
                    if maxfreq >= 1/3
                        nap_timing = nap_time_table.Properties.VariableNames{maxind};
                    else
                        nap_timing = 'mixed';
                    end
                else
                    nap_duration_in_min_per_day = 0;
                    nap_frequency_per_day       = 0;
                    nap_timing                  = 'never';
                end
                
                fprintf(fid, '%s,%.1f,%.1f,%s,%d\r', app.currentFileName, nap_duration_in_min_per_day, nap_frequency_per_day, nap_timing, acti_length_in_day);
                
                fclose(fid);
                
                app.statusFeed('Nap estimated and saved with summary estimated and saved.');
            else
                app.statusFeed('Nap estimated and saved.');
            end
        end
        
        function doSleep(app)
            if ~(isempty(app.sleep.data))
                delete(app.sleep.handle); app.sleep.handle = [];
                delete(app.sleep.text); app.sleep.text = [];
                app.sleep.data = [];
            end
            
            % masking has been included in @acti.sleepDet
            % % time restrition mask
            % dayMask = doMask2(length(app.data), app.epoch, app.startTime, app.sleep.parameter.startTime, app.sleep.parameter.endTime);
            
            % sleepSeries = doSleepDet(app.data, app.epoch, app.sleep.parameter.modeParameter.V, app.sleep.parameter.modeParameter.P, app.sleep.parameter.modeParameter.C, 5);
            
            % temp = sleepSeries & dayMask;
            % app.sleep.data = detConstantOne(temp);
            
            % sleep detection
            % 5/14/2021
            % adapt to new sleep detection algorithm based on acti class
            quality = ones(size(app.data, 1), 1);
            if ~isempty(app.sleep.gap.data)
                for iS = 1:size(app.sleep.gap.data, 1)
                    quality(app.sleep.gap.data(iS, 1):app.sleep.gap.data(iS, 2)) = 0;
                end
            end
            window = struct('StartTime', app.sleep.parameter.startTime, 'EndTime', app.sleep.parameter.endTime);
            parameter = struct('P', app.sleep.parameter.modeParameter.P, 'V', app.sleep.parameter.modeParameter.V, 'C', app.sleep.parameter.modeParameter.C);
            [toFile, res, ~] = detSleep(app.data, app.epoch, app.startTime, quality, window, parameter);
            
            app.sleep.data = toFile;
            
            % update Face
            app.updateItem;
            app.statusFeed('Sleep detection done!')
            
            % summary
            if app.sleep.report
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end
                
                if ~(exist(fullfile(writePt, 'report.SLEEP'), 'file') == 2)
                    fid = fopen(fullfile(writePt, 'report.SLEEP'), 'w');
                    fprintf(fid, '%s,%s,%s,%s,%s\n', 'filename', ...
                        ['sleep_duration_in_hour_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['sleep_frequency_in_times_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['times_awake_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['waso_in_min_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')]);
                else
                    fid = fopen(fullfile(writePt, 'report.SLEEP'), 'a');
                end
                
                %                 acti_length_in_day = length(app.data) * app.epoch / 3600 / 24;
                %
                %                 if ~isempty(app.sleep.data)
                %                     total_sleep_time_in_hour_per_day = sum(app.sleep.data(:, 2) - app.sleep.data(:, 1) + 1) * app.epoch / 60 / 60 / acti_length_in_day;
                %                     time_awaken_avg = (size(app.sleep.data, 1) - (acti_length_in_day - 1)) ./ acti_length_in_day;
                %                 else
                %                     total_sleep_time_in_hour_per_day = 0;
                %                     time_awaken_avg = nan;
                %                 end
                
                fprintf(fid, '%s,%f,%f,%f,%f\n', app.currentFileName, res);
                
                fclose(fid);
                
                app.statusFeed('Sleep estimated and saved with summary estimated and saved.');
            else
                app.statusFeed('Sleep estimated and saved.');
            end
        end
        
        function resetDisplay(app)
            if isa(app.actigraphyApp, 'actigraphy')
                if isempty(findobj('Tag', 'actigraphyFig'))
                    app.actigraphyApp = actigraphy(app);
                else
                    cla(app.actigraphyApp.actiAxis);
                end
            else
                if ~isempty(findobj('Tag', 'actigraphyFig'))
                    delete(findobj('Tag', 'actigraphyFig'));
                end
                app.actigraphyApp = actigraphy(app);
            end
            
            % reset all handles
            app.(lower(app.mode)).handle = [];
            app.(lower(app.mode)).text   = [];
            
            app.(lower(app.mode)).gap.handle = [];
            app.(lower(app.mode)).gap.text   = [];
            
            app.gImpute = [];
            app.gSig = [];
        end
        
        function updateGap(app)
            epi    = app.(lower(app.mode)).gap.data;
            Yrange = app.yrange;
            
            % update plot
            if ~isempty(app.(lower(app.mode)).gap.handle)
                delete(app.(lower(app.mode)).gap.handle);
                app.(lower(app.mode)).gap.handle = [];
            end
            
            if ~isempty(app.(lower(app.mode)).gap.text)
                delete(app.(lower(app.mode)).gap.text);
                app.(lower(app.mode)).gap.text = [];
            end
            for iG = 1:size(epi, 1)
                app.(lower(app.mode)).gap.handle(iG) = patch('Parent', app.actigraphyApp.actiAxis, 'XData', [epi(iG, 1) epi(iG, 1) epi(iG, 2) epi(iG, 2)], ...
                    'YData', [Yrange(1) Yrange(1)+.05*diff(Yrange) Yrange(1)+.05*diff(Yrange) Yrange(1)], 'FaceColor', [.4 .4 .4], 'EdgeColor', 1-(1-.2*[.4 .4 .4]), 'FaceAlpha', 0.2);
                app.(lower(app.mode)).gap.text(iG) = text(app.actigraphyApp.actiAxis, epi(iG, 1), Yrange(1) + .03*diff(Yrange), ['Gap: # ' num2str(iG)], 'Color', [.4 .4 .4], 'Rotation', 30);
            end
            
            
            % switch layers
            child = app.actigraphyApp.actiAxis.Children;
            child = [child(end-length(app.gImpute):end); child(1:end-length(app.gImpute)-1)];
            app.actigraphyApp.actiAxis.Children = child;
            
            drawnow;
        end
        
        function reset(app)
            allModes = {'circadian', 'nap', 'sleep', 'gap'};
            for iM   = 1:numel(allModes)
                app.(allModes{iM}).data = [];
                app.(allModes{iM}).gap.data = [];
            end
        end
        
        function loadActi(app)
            app.message = "Directory: " + app.filePath;
            
            loadData(app);
            
            if app.dataLoaded
                loadExist(app);
            end
            
            if app.dataLoaded && ~strcmpi(app.mode, 'gap') && app.config.Gap
                loadGap(app);
            end
            
            app.TextArea.Value = app.message;
            app.message = [];
            
            function loadData(app)
                % load data
                currentFile = fullfile(app.filePath, app.currentFileName);
                
                if ~(exist(currentFile, 'file') == 2)
                    app.message    = [app.message; ""; app.currentFileName + ": not exist!"];
                    app.dataLoaded = 0;
                    return;
                end
                
                % data
                recording   = load(currentFile);
                app.message = [app.message; ""; app.currentFileName];
                app.message = [app.message; "  Length: " + numel(recording) + " points."];
                
                % epoch
                switch app.config.EpochParserMode
                    case 'All Same'
                        app.epoch = app.config.EpochParserProperties.SameEpoch;
                        app.message = [app.message; "  Epoch: " + num2str(app.epoch) + " seconds."];
                        app.message = [app.message; "  Duration: " + sprintf('%.2f', numel(recording)*app.epoch/3600) + " hours."];
                    case 'From Filename ezDecoder'
                        nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                        try
                            dtparts   = nameparts{app.config.EpochParserProperties.EpochInd};
                            dt        = regexp(dtparts, '\d*', 'match');
                            app.epoch = eval(dt{1});
                            app.message = [app.message; "  Epoch: " + num2str(app.epoch) + " seconds."];
                            app.message = [app.message; "  Duration: " + sprintf('%.2f', numel(recording)*app.epoch/3600) + " hours."];
                        catch
                            app.epoch   = nan;
                            app.message = [app.message; "  Epoch: CANNOT parse filename."];
                            app.message = [app.message; "  Duration: N/A."];
                        end
                    case 'From File'
                    case 'None'
                        app.epoch   = 1;
                        app.message = [app.message; "  Epoch: N/A."];
                        app.message = [app.message; "  Duration: N/A."];
                end
                
                % time
                switch app.config.TimeParserMode
                    case 'From File'
                        [~, fileID] = fileparts(app.currentFileName);
                        if any(string(app.startTimeFileInfo.FileID) == string(fileID))
                            app.timeSet   = 1;
                            app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
                        else
                            app.timeSet   = 0;
                        end
                    case 'From Filename ezDecoder'
                        nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                        try
                            app.startTime = datetime(...
                                nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
                                'InputFormat', ...
                                app.config.TimeParserProperties.DatetimeFormat);
                            app.timeSet   = 1;
                            
                            app.message   = [app.message; "  Start Time: " + datestr(app.startTime) + " seconds."];
                        catch
                            app.timeSet   = 0;
                            app.message   = [app.message; "  Start Time: CANNOT parse filename."];
                        end
                    case 'All Same'
                        app.startTime = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                        app.timeSet   = 1;
                        app.message   = [app.message; "  Start Time: " + datestr(app.startTime) + " seconds."];
                    case 'None'
                        app.timeSet   = 0;
                        app.startTime = NaT;
                        app.message   = [app.message; "  Start Time: N/A."];
                end
                
                app.message = [app.message; ""];
                
                app.data2 = acti(recording, 'StartTime', app.startTime, 'Epoch', app.epoch);
                app.dataLoaded = 1;
                
                % propagate to ezActi properties
                app.data        = app.data2.Data;
                app.dataImputed = app.data;
                app.x           = app.data2.Point;
            end
            
            function loadExist(app)
                if app.config.ExistedFlag
                    currentEpi = fullfile(app.filePath, app.mode, [app.currentFileNameNoExt app.importedEpiSuffix]);
                    if ~(exist(currentEpi, 'file') == 2)
                        app.message = [app.message; "./" + app.mode + "/" + [app.currentFileNameNoExt app.importedEpiSuffix] + " NOT FOUND."];
                        app.(lower(app.mode)).data = [];
                    else
                        app.(lower(app.mode)).data = load(currentEpi);
                        app.message = [app.message; "./" + app.mode + "/" + [app.currentFileNameNoExt app.importedEpiSuffix] + " loaded."];
                        app.(lower(app.mode)).matchFile = app.currentFileName;
                    end
                end
            end
            
            function loadGap(app)
                currentEpi = fullfile(app.filePath, 'Gap', [app.currentFileNameNoExt app.importedGapSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.mesasge  = [app.message; "./Gap/" + [app.currentFileNameNoExt app.importedGapSuffix] + " NOT FOUND."];
                    app.(lower(app.mode)).gap.data = [];
                else
                    app.(lower(app.mode)).gap.data = load(currentEpi);
                    app.message  = [app.message; "./Gap/" + [app.currentFileNameNoExt app.importedGapSuffix] + " loaded."];
                end
            end
        end
        
        function closeChildren(app)
            if isa(app.actigraphyApp, 'actigraphy')
                if isvalid(app.actigraphyApp)
                    delete(app.actigraphyApp);
                end
            else
                if ~isempty(findobj('Tag', 'actigraphyFig'))
                    delete(findobj('Tag', 'actigraphyFig'));
                end
            end
            
            if ~isempty(findobj('Tag', 'DFAConf'))
                delete(findobj('Tag', 'DFAConf'));
            end
            if ~isempty(findobj('Tag', 'dfaPlotFig'))
                delete(findobj('Tag', 'dfaPlotFig'));
            end
        end
    end
    
    methods (Access = public)
        function loadData(app)
            if ~app.dataDefined
                return;
            end
            
            if app.startIndex > length(app.fileList)
                app.statusFeed('Already the last file!', 'warn');
                app.startIndex = app.startIndex + 1; % this makes sure the auto loop can end succesfully
                return;
            end
            
            app.ListBox.Value   = app.startIndex;
            app.currentFileName = app.fileList{app.startIndex};
            
            % scroll list box
            if app.startIndex > 10
                scroll(app.ListBox, app.startIndex);
            end
            
            % load data
            currentFile = fullfile(app.filePath, app.currentFileName);
            
            if ~(exist(currentFile, 'file') == 2)
                app.statusFeed(['No such a file: ' app.currentFileName], 'r');
                return;
            end
            
            recording   = load(currentFile);
            recording   = recording(:, 1);
            % this eventually won't be a good solution as -10 might be
            % meaningful for other data, NEED TO ALWAYS REMEMBER TO CHECK
            % NAN IN OTHER FUNCTIONS
            % recording(isnan(recording)) = -10; % to better visually identify single NaN
            app.data        = recording;
            app.dataImputed = recording;       % shadowing data
            app.x           = 1:size(app.data, 1);
            
            % ezActi2
            % time
            switch app.config.TimeParserMode
                case 'From File'
                    [~, fileID] = fileparts(app.currentFileName);
                    if any(string(app.startTimeFileInfo.FileID) == string(fileID))
                        app.timeSet   = 1;
                        app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
                    else
                        app.timeSet   = 0;
                    end
                case 'From Filename ezDecoder'
                    nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                    app.startTime = datetime(...
                        nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
                        'InputFormat', ...
                        app.config.TimeParserProperties.DatetimeFormat);
                    app.timeSet   = 1;
                case 'All Same'
                    app.startTime = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                    app.timeSet   = 1;
                case 'None'
                    app.timeSet   = 0;
                    app.startTime = NaT;
            end
            
            % epoch
            switch app.config.EpochParserMode
                case 'All Same'
                    app.epoch = app.config.EpochParserProperties.SameEpoch;
            end
            
            app.data2 = acti(recording, 'StartTime', app.startTime, 'Epoch', app.epoch);
        end
        
        function loadGap(app)
            currentEpi = fullfile(app.filePath, [upper(app.importedGapSuffix(2)) app.importedGapSuffix(3:end)], [app.currentFileName(1:end-4) app.importedGapSuffix]);
            if ~(exist(currentEpi, 'file') == 2)
                app.statusFeed(['Showing ' app.currentFileName '. No file: ' app.currentFileName(1:end-4) app.importedGapSuffix ' from import found.'], 'warn');
                app.gap.data = [];
            else
                app.gap.data = load(currentEpi);
                app.statusFeed(['Showing ' app.currentFileName ' with imported file: ' app.currentFileName(1:end-4) app.importedGapSuffix]);
                app.gap.matchFile = app.currentFileName;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.SelectedTab = app.GapTab;
            app.mode = app.GapTab.Title;
            app.statusFeed("Ready");
        end

        % Menu selected function: ListfileMenu
        function ListfileMenuSelected(app, event)
            called = listImportWizard;
            app.statusFeed('Waiting for importing data ...');
            uiwait(called.wizardFigure)
            if called.imported
                app.Status.Text   = [num2str(length(called.FileList)) ' files imported.'];
                app.filePath      = called.DataPath;
                app.fileList      = called.FileList;
                
                app.ListBox.Items = app.fileList;
                app.ListBox.ItemsData = 1:length(app.fileList);
                app.startIndex    = 1;
                app.ListBox.Value = 1;
                
                app.PlotButton.Text = 'Run';
                
                app.EpochEditField.Value = called.epoch;
                app.epoch                = str2double(called.epoch);
                
                app.dataDefined = 1;
                app.importedEpi = called.epiImported;
                app.importedEpiSuffix = called.epiSuffix;
                
                app.importedGap = called.gapImported;
                app.importedGapSuffix = called.gapSuffix;
                
                app.mode = [upper(app.importedEpiSuffix(2)) app.importedEpiSuffix(3:end)];
            else
                app.statusFeed('No data imported.');
                app.dataDefined = 0;
            end
            
            if app.dataDefined
                app.TabGroup.SelectedTab = eval(['app.' app.mode 'Tab']);
            end
            
            called.delete;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.startIndex      = app.ListBox.Value;
            app.currentFileName = app.fileList{app.startIndex};
            
            temp = strsplit(app.currentFileName, '.');
            app.currentFileNameNoExt = temp{1};
            
            % ezActi2
            % load data here, and update file info
            loadActi(app);
            
            % do plot when click Plot button
            app.PlotButton.Text = 'Plot';
            app.statusFeed('Click Plot button to plot the signal');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % reset
            app.reset;
            
            switch event.Source.Text
                case 'Next'
                    % load next data
                    if app.startIndex > length(app.fileList)
                        app.statusFeed('No more files', 'warn');
                        return;
                    end
                    app.ListBox.Value   = app.startIndex;
                    app.currentFileName = app.fileList{app.startIndex};
                    
                    temp = strsplit(app.currentFileName, '.');
                    app.currentFileNameNoExt = temp{1};
                    
                    loadActi(app);
            end
            
            % show data: reset display
            app.resetDisplay;
            
            % show data: reset control
            eval(['app.reset' app.mode 'Control']);
            
            % show data: show
            dataShow(app);
            
            if app.importedEpi
                currentEpi = fullfile(app.filePath, [upper(app.importedEpiSuffix(2)) app.importedEpiSuffix(3:end)], [app.currentFileName(1:end-4) app.importedEpiSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.statusFeed(['Showing ' app.currentFileName '. No file: ' app.currentFileName(1:end-4) app.importedEpiSuffix ' from import found.'], 'warn');
                    app.(app.importedEpiSuffix(2:end)).data = [];
                else
                    app.(app.importedEpiSuffix(2:end)).data = load(currentEpi);
                    
                    % update
                    app.updateItem;
                    app.statusFeed(['Showing ' app.currentFileName ' with imported file: ' app.currentFileName(1:end-4) app.importedEpiSuffix]);
                    
                    app.(lower(app.mode)).matchFile = app.currentFileName;
                end
            else
                app.statusFeed(['Showing ' app.currentFileName]);
            end
            
            if app.importedGap
                currentEpi = fullfile(app.filePath, [upper(app.importedGapSuffix(2)) app.importedGapSuffix(3:end)], [app.currentFileName(1:end-4) app.importedGapSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.statusFeed(['. No file: ' app.currentFileName(1:end-4) app.importedGapSuffix ' from import found.'], 'warn', app.Status.Text);
                    
                    % propergate
                    app.circadian.gap.data = [];
                    app.sleep.gap.data     = [];
                    app.nap.gap.data       = [];
                    app.gap.gap.data       = [];
                else
                    gapData = load(currentEpi);
                    
                    % propergate
                    app.circadian.gap.data = gapData;
                    app.sleep.gap.data     = gapData;
                    app.nap.gap.data       = gapData;
                    app.gap.gap.data       = gapData;
                    
                    % update
                    app.updateGap;
                    app.statusFeed([' with imported file: ' app.currentFileName(1:end-4) app.importedGapSuffix]);
                end
            end
            
            app.dataShown = 1;
            
            app.PlotButton.Text = 'Next';
            app.startIndex = app.startIndex + 1;
        end

        % Close request function: ActigraphycontrolpanelUIFigure
        function ActigraphycontrolpanelUIFigureCloseRequest(app, event)
            app.closeChildren;
            
            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.GapTab
                    app.previewUITable.Visible = 'on';
                    app.InterpButton.Enable    = 'on';
                    app.CropButton.Enable      = 'on';
                    app.FlipButton.Enable      = 'on';
                    
                    app.circShowArea.Visible   = 'off';
                    
                    app.resetGapControl;
                    
                    if ~strcmp(app.gap.matchFile, app.currentFileName)
                        app.previewUITable.Data = [];
                        app.gap.data            = [];
                    end
                case {app.SleepTab, app.NapTab}
                    app.previewUITable.Visible = 'on';
                    
                    app.InterpButton.Enable    = 'off';
                    app.CropButton.Enable      = 'on';
                    app.FlipButton.Enable      = 'on';
                    
                    app.circShowArea.Visible   = 'off';
                case app.CircadianTab
                    app.previewUITable.Visible = 'off';
                    app.InterpButton.Enable    = 'off';
                    app.CropButton.Enable      = 'off';
                    app.FlipButton.Enable      = 'off';
                    
                    app.circShowArea.Visible   = 'on';
                    
                    if ~strcmp(app.circadian.matchFile, app.currentFileName)
                        app.circShowArea.Value = '';
                        app.circadian.data     = [];
                    end
            end
            app.mode = selectedTab.Title;
            app.statusFeed(['Run mode defined as: ' app.mode]);
        end

        % Value changed function: ManAutoSwitch
        function ManAutoSwitchValueChanged(app, event)
            value = app.ManAutoSwitch.Value;
            app.gap.parameter.method = value;
            switch value
                case 'Manual'
                    enable(app, [app.StartEditField, ...
                        app.StartEditFieldLabel, ...
                        app.EndEditField, ...
                        app.EndEditFieldLabel]);
                    
                    disable(app, [app.ThreEditField, ...
                        app.ThreEditFieldLabel, ...
                        app.MinDurEditField, ...
                        app.MinDurEditFieldLabel, ...
                        app.minLabel, ...
                        app.threUnitDropDown, ...
                        app.MergeifEditField, ...
                        app.MergeifEditFieldLabel, ...
                        app.minLabel_2]);
                case 'Auto'
                    disable(app, [app.StartEditField, ...
                        app.StartEditFieldLabel, ...
                        app.EndEditField, ...
                        app.EndEditFieldLabel]);
                    
                    enable(app, [app.ThreEditField, ...
                        app.ThreEditFieldLabel, ...
                        app.MinDurEditField, ...
                        app.MinDurEditFieldLabel, ...
                        app.minLabel, ...
                        app.threUnitDropDown, ...
                        app.MergeifEditField, ...
                        app.MergeifEditFieldLabel, ...
                        app.minLabel_2]);
                    
                    app.statusFeed('Caution: automated detection will overwrite previously detected gaps.', 'warn');
            end
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.gap.parameter.startIndex = str2double(app.StartEditField.Value);
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.gap.parameter.endIndex = str2double(app.EndEditField.Value);
        end

        % Button pushed function: DoButton
        function DoButtonPushed(app, event)
            if ~app.dataShown
                return;
            end
            
            app.(['do' app.mode]);
            app.(lower(app.mode)).matchFile = app.currentFileName;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~app.dataShown
                return;
            end
            
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: nothing to clear.', [1 .325 .286]);
                return;
            end
            
            delete(app.(lower(app.mode)).handle); app.(lower(app.mode)).handle = [];
            
            if ~strcmp(app.mode, 'Circadian')
                delete(app.(lower(app.mode)).text); app.(lower(app.mode)).text = [];
                app.previewUITable.Data = [];
            else
                try delete(app.(lower(app.mode)).text); end
                app.(lower(app.mode)).text = [];
                app.circShowArea.Value = '';
            end
            
            app.(lower(app.mode)).data = [];
            
            app.statusFeed(['Detected ' app.mode '(s) cleared.']);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            if ~app.dataShown
                return;
            end
            
            if isempty(app.(lower(app.mode)).data)
                if exist(fullfile(app.filePath, app.mode, [app.currentFileName(1:end-4) '.' lower(app.mode)]), 'file') == 2
                    delete(fullfile(app.filePath, app.mode, [app.currentFileName(1:end-4) '.' lower(app.mode)]));
                    app.statusFeed('Overwriting existing file: deleted (no detectables)!', 'warn');
                else
                    app.statusFeed('Nothing to write.', 'warn');
                end
                return;
            end
            
            writePt = fullfile(app.filePath, app.mode);
            if ~ (exist(writePt, 'dir') == 7)
                mkdir(writePt);
            end
            
            switch app.mode
                case 'Circadian'
                    f = '%f\n';
                case {'Gap', 'Nap', 'Sleep'}
                    f = '%d,%d\n';
            end
            fid = fopen(fullfile(writePt, [app.currentFileName(1:end-4) '.' lower(app.mode)]), 'w');
            fprintf(fid, f, app.(lower(app.mode)).data');
            fclose(fid);
            
            if app.(lower(app.mode)).report
                app.statusFeed(['Detected ' app.mode ' saved with summary report.']);
            else
                app.statusFeed(['Detected ' app.mode ' saved.']);
            end
        end

        % Cell edit callback: previewUITable
        function previewUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if ~isempty(newData)
                if isnan(newData) % delete a gap
                    app.(lower(app.mode)).data(indices(1), :) = [];
                    delete(app.(lower(app.mode)).handle(indices(1)));
                    delete(app.(lower(app.mode)).text(indices(1)));
                    app.(lower(app.mode)).handle(indices(1)) = [];
                    app.(lower(app.mode)).text(indices(1)) = [];
                    
                    % update table
                    set(app.previewUITable, 'Data', app.(lower(app.mode)).data);
                    
                    % update text display
                    for iT = 1:length(app.(lower(app.mode)).text)
                        set(app.(lower(app.mode)).text(iT), 'String', [app.mode ': # ' num2str(iT)], 'Color', 'm');
                    end
                else
                    app.(lower(app.mode)).data(indices(1), indices(2)) = newData;
                    
                    % update plot
                    set(app.(lower(app.mode)).handle(indices(1)), 'XData', [app.(lower(app.mode)).data(indices(1), 1) app.(lower(app.mode)).data(indices(1), 1) app.(lower(app.mode)).data(indices(1), 2) app.(lower(app.mode)).data(indices(1), 2)]);
                    OrigPos    = get(app.(lower(app.mode)).text(indices(1)), 'Position');
                    OrigPos(1) = app.(lower(app.mode)).data(indices(1), 1);
                    set(app.(lower(app.mode)).text(indices(1)), 'Position', OrigPos);
                end
            end
        end

        % Value changed function: ThreEditField
        function ThreEditFieldValueChanged(app, event)
            app.gap.parameter.threshold = eval(app.ThreEditField.Value);
        end

        % Value changed function: MinDurEditField
        function MinDurEditFieldValueChanged(app, event)
            app.gap.parameter.minDuration = str2double(app.MinDurEditField.Value);
        end

        % Value changed function: threUnitDropDown
        function threUnitDropDownValueChanged(app, event)
            app.gap.parameter.thresholdUnit = app.threUnitDropDown.Value;
            switch app.gap.parameter.thresholdUnit
                case 'Range'
                    app.ThreEditField.Value = '[a,b]';
                otherwise
                    app.ThreEditField.Value = '';
            end
        end

        % Value changed function: MergeifEditField
        function MergeifEditFieldValueChanged(app, event)
            app.gap.parameter.minDistance = str2double(app.MergeifEditField.Value);
        end

        % Cell selection callback: previewUITable
        function previewUITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(app.(lower(app.mode)).data)
                set(app.(lower(app.mode)).handle, 'FaceColor', 'm', 'EdgeColor', 'm');
                set(app.(lower(app.mode)).text, 'Color', 'm');
                
                if ~isempty(indices) % in case conflict from editing table (delete a gap)
                    set(app.(lower(app.mode)).handle(indices(:, 1)), 'FaceColor', 'g', 'EdgeColor', 'g');
                    set(app.(lower(app.mode)).text(indices(:, 1)), 'Color', 'g');
                end
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no episode info to help crop data.', 'warn');
                return;
            end
            
            writePt = fullfile(app.filePath, 'Crop');
            if ~(exist(writePt, 'dir') == 7)
                mkdir(writePt);
            end
            
            for iE  = 1:size(app.(lower(app.mode)).data, 1)
                fid = fopen(fullfile(writePt, [app.currentFileName(1:end-4) '_start_index_' num2str(app.(lower(app.mode)).data(iE, 1)) '.crop']), 'w');
                fprintf(fid, '%f\r', app.dataImputed(app.(lower(app.mode)).data(iE, 1):app.(lower(app.mode)).data(iE, 2)));
                fclose(fid);
            end
            
            app.statusFeed(['In total ' num2str(size(app.(lower(app.mode)).data, 1)) ' cropped data saved!']);
        end

        % Button pushed function: InterpButton
        function InterpButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no missing data to impute.', 'warn');
                return;
            end
            called = fitHelper(app);
            app.statusFeed('Waiting for configuration ...');
            uiwait(called.wizardFigure)
            if called.go
                app.gapImputationMethod = called.fitMethod;
                app.gapImputationWindow = called.fitWindowLength;
                app.gapImputationCrit   = called.fitIfLessThan;
                app.gapImputationSave   = called.fitSave;
                
                called.delete;
                
                app.gapImputation;
            else
                app.statusFeed('Data imputation was not corrected configured.');
                
                called.delete;
                return;
            end
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no episodes to flip', 'warn');
                return;
            end
            app.(lower(app.mode)).data = transSegGap(app.(lower(app.mode)).data, length(app.data));
            app.updateItem;
            app.statusFeed('Caution: episodes flipped but not saved!', 'warn');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.circadian.parameter.method = value;
        end

        % Value changed function: TargetcomponentEditField
        function TargetcomponentEditFieldValueChanged(app, event)
            value = app.TargetcomponentEditField.Value;
            app.circadian.parameter.target = value;
        end

        % Value changed function: HarmonicsEditField
        function HarmonicsEditFieldValueChanged(app, event)
            value = app.HarmonicsEditField.Value;
            if isempty(value)
                app.circadian.parameter.harmonic = [];
            else
                app.circadian.parameter.harmonic = eval(value);
            end
        end

        % Value changed function: MinlengthEditField
        function MinlengthEditFieldValueChanged(app, event)
            value = app.MinlengthEditField.Value;
            app.circadian.parameter.minLength = eval(value);
        end

        % Value changed function: SummaryreportCheckBox
        function SummaryreportCheckBoxValueChanged(app, event)
            value = app.SummaryreportCheckBox.Value;
            app.circadian.report = value;
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            % put other callbacks on queue
            % app.AutoButton.Interruptible = 'off';
            
            while app.startIndex <= length(app.fileList)
                app.PlotButtonPushed;
                app.DoButtonPushed;
                app.WriteButtonPushed;
                
                drawnow;
            end
            
            app.statusFeed('Finished!')
        end

        % Menu selected function: AboutezActiMenu
        function AboutezActiMenuSelected(app, event)
            AboutEZ("About ezActi", "ezacti.png", {'Version: EZ.2021.sp (0.0.0527)'; 'May 27, 2021'; 'Licence: developer'});
        end

        % Value changed function: SummaryreportCheckBox_Nap
        function SummaryreportCheckBox_NapValueChanged(app, event)
            value = app.SummaryreportCheckBox_Nap.Value;
            app.nap.report = value;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            app.nap.parameter.method = value;
            
            switch value
                case 'Proportional integrating'
                    app.nap.parameter.modeParameter.P = 0.001;
                    app.nap.parameter.modeParameter.V = [106 54 58 76 230 74 67];
                    app.nap.parameter.modeParameter.C = 0;
            end
            
            % to cross ref sleep mode
            app.ModeDropDown_Sleep.Value = value;
            app.sleep.parameter.modeParameter = app.nap.parameter.modeParameter;
        end

        % Value changed function: DaytimeStartEditField
        function DaytimeStartEditFieldValueChanged(app, event)
            value = app.DaytimeStartEditField.Value;
            app.nap.parameter.startTime = value;
        end

        % Value changed function: DaytimeEndEditField
        function DaytimeEndEditFieldValueChanged(app, event)
            value = app.DaytimeEndEditField.Value;
            app.nap.parameter.endTime = value;
        end

        % Value changed function: ModeDropDown_Sleep
        function ModeDropDown_SleepValueChanged(app, event)
            value = app.ModeDropDown_Sleep.Value;
            app.sleep.parameter.method = value;
            
            switch value
                case 'Proportional integrating'
                    app.sleep.parameter.modeParameter.P = 0.001;
                    app.sleep.parameter.modeParameter.V = [106 54 58 76 230 74 67];
                    app.sleep.parameter.modeParameter.C = 0;
            end
            
            % to cross ref nap mode
            app.ModeDropDown.Value = value;
            app.nap.parameter.modeParameter = app.sleep.parameter.modeParameter;
        end

        % Value changed function: SleepStartEditField
        function SleepStartEditFieldValueChanged(app, event)
            value = app.SleepStartEditField.Value;
            app.sleep.parameter.startTime = value;
        end

        % Value changed function: SleepEndEditField
        function SleepEndEditFieldValueChanged(app, event)
            value = app.SleepEndEditField.Value;
            app.sleep.parameter.endTime = value;
        end

        % Menu selected function: 
        % DetrendedfluctuationanalysisDFAMenu
        function DetrendedfluctuationanalysisDFAMenuSelected(app, event)
            dfaConfig(app);
            app.statusFeed('Controlling DFA on DFA Configuration. Wait to finish ...', 'warn');
        end

        % Value changed function: SummaryreportCheckBox_Sleep
        function SummaryreportCheckBox_SleepValueChanged(app, event)
            value = app.SummaryreportCheckBox_Sleep.Value;
            app.sleep.report = value;
        end

        % Menu selected function: ImportWizardMenu
        function ImportWizardMenuSelected(app, event)
            called = ImportWizard(event.Source.Text);
            app.statusFeed('Waiting for importing data ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.Status.Text   = [num2str(numel(called.selectedFiles)) ' files imported.'];
                app.filePath      = called.dataFolder;
                app.fileList      = called.selectedFiles;
                
                app.ListBox.Items     = app.fileList;
                app.ListBox.ItemsData = 1:length(app.fileList);
                app.startIndex        = 1;
                app.ListBox.Value     = 1;
                
                app.config = called.configStruct;
                
                % adapt to ezActi previous version
                switch app.config.TimeParserMode
                    case 'none'
                        app.timeDispMode = 0;
                    case 'All Same'
                        app.timeDispMode = 3;
                        app.startTime    = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                        app.timeSet      = 1;
                    case 'From Filename ezDecoder'
                        app.timeDispMode = 2;
                    case 'From File'
                        app.timeDispMode = 1;
                end
                
                switch app.config.EpochParserMode
                    case 'All Same'
                        app.epoch = app.config.EpochParserProperties.SameEpoch;
                end
                %
                %                 app.PlotButton.Text = 'Next';
                %                 app.epoch = str2double(called.epoch);
                %
                app.importedEpi       = called.configStruct.ExistedFlag;
                app.importedEpiSuffix = convertStringsToChars(called.configStruct.ExistedExtension);
                
                app.importedGap       = called.configStruct.Gap;
                app.importedGapSuffix = convertStringsToChars(called.configStruct.GapExtension);
                
                app.mode        = app.config.RunMode;
                app.dataDefined = 1;
            else
                app.statusFeed('No data imported.');
                % app.dataDefined = 0;
            end
            
            if app.dataDefined
                app.TabGroup.SelectedTab = eval(['app.' app.mode 'Tab']);
                
                app.closeChildren;
            end
            
            called.delete;
            
            % load first signal
            app.ListBoxValueChanged(event);
        end

        % Menu selected function: OptionMenu
        function OptionMenuSelected(app, event)
            called = ImportWizard(event.Source.Text, app.config, app.fileList);
            
            app.statusFeed('Configuring the App ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.Status.Text  = 'Configuration done.';
                
                app.config = called.configStruct;
                
                % adapt to ezActi previous version
                switch app.config.TimeParserMode
                    case 'none'
                        app.timeDispMode = 0;
                    case 'All Same'
                        app.timeDispMode = 3;
                        app.startTime    = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                        app.timeSet      = 1;
                    case 'From Filename ezDecoder'
                        app.timeDispMode = 2;
                    case 'From File'
                        app.timeDispMode = 1;
                end
                
                switch app.config.EpochParserMode
                    case 'All Same'
                        app.epoch = app.config.EpochParserProperties.SameEpoch;
                end
                
                app.importedEpi       = called.configStruct.ExistedFlag;
                app.importedEpiSuffix = called.configStruct.ExistedExtension;
                
                app.importedGap       = called.configStruct.Gap;
                app.importedGapSuffix = called.configStruct.GapExtension;
                
                app.mode        = app.config.RunMode;
            else
                app.statusFeed('Nothing changed!');
            end
            
            if called.imported
                app.TabGroup.SelectedTab = eval(['app.' app.mode 'Tab']);
            end
            
            called.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActigraphycontrolpanelUIFigure = uifigure('Visible', 'off');
            app.ActigraphycontrolpanelUIFigure.Name = 'Actigraphy: control panel';
            app.ActigraphycontrolpanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActigraphycontrolpanelUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.FileMenu.Text = '&File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.SinglefileMenu = uimenu(app.OpenMenu);
            app.SinglefileMenu.Text = 'Single file';
            app.ListfileMenu = uimenu(app.OpenMenu);
            app.ListfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ListfileMenuSelected, true);
            app.ListfileMenu.Text = 'List file';
            app.ImportWizardMenu = uimenu(app.FileMenu);
            app.ImportWizardMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportWizardMenuSelected, true);
            app.ImportWizardMenu.Separator = 'on';
            app.ImportWizardMenu.Accelerator = 'I';
            app.ImportWizardMenu.Text = '&Import Wizard';
            app.OptionMenu = uimenu(app.FileMenu);
            app.OptionMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionMenuSelected, true);
            app.OptionMenu.Separator = 'on';
            app.OptionMenu.Accelerator = 'P';
            app.OptionMenu.Text = '&Option';
            app.AdvancedMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.AdvancedMenu.Text = 'Advanced';
            app.DetrendedfluctuationanalysisDFAMenu = uimenu(app.AdvancedMenu);
            app.DetrendedfluctuationanalysisDFAMenu.MenuSelectedFcn = createCallbackFcn(app, @DetrendedfluctuationanalysisDFAMenuSelected, true);
            app.DetrendedfluctuationanalysisDFAMenu.Accelerator = 'D';
            app.DetrendedfluctuationanalysisDFAMenu.Text = 'Detrended fluctuation analysis (DFA)';
            app.HelpMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.HelpMenu.Text = '&Help';
            app.AboutezActiMenu = uimenu(app.HelpMenu);
            app.AboutezActiMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutezActiMenuSelected, true);
            app.AboutezActiMenu.Text = 'About ezActi';
            app.GridLayout = uigridlayout(app.ActigraphycontrolpanelUIFigure);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.FlipButton = uibutton(app.GridLayout, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Icon = 'flip.png';
            app.FlipButton.Text = '';
            app.InterpButton = uibutton(app.GridLayout, 'push');
            app.InterpButton.ButtonPushedFcn = createCallbackFcn(app, @InterpButtonPushed, true);
            app.InterpButton.Icon = 'interp.png';
            app.InterpButton.Text = '';
            app.CropButton = uibutton(app.GridLayout, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Icon = 'crop.png';
            app.CropButton.Text = '';
            app.WriteButton = uibutton(app.GridLayout, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Icon = 'write.png';
            app.WriteButton.Text = '';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'eraser.png';
            app.ClearButton.Text = '';
            app.DoButton = uibutton(app.GridLayout, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Icon = 'define.png';
            app.DoButton.Text = '';
            app.previewUITable = uitable(app.GridLayout);
            app.previewUITable.ColumnName = {'Start'; 'End'};
            app.previewUITable.RowName = {};
            app.previewUITable.ColumnEditable = true;
            app.previewUITable.CellEditCallback = createCallbackFcn(app, @previewUITableCellEdit, true);
            app.previewUITable.CellSelectionCallback = createCallbackFcn(app, @previewUITableCellSelection, true);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Status = uilabel(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CircadianTab = uitab(app.TabGroup);
            app.CircadianTab.Title = 'Circadian';
            app.GridLayout2 = uigridlayout(app.CircadianTab);
            app.SummaryreportCheckBox = uicheckbox(app.GridLayout2);
            app.SummaryreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @SummaryreportCheckBoxValueChanged, true);
            app.SummaryreportCheckBox.Text = 'Summary report?';
            app.SummaryreportCheckBox.Value = true;
            app.hourLabel_3 = uilabel(app.GridLayout2);
            app.hourLabel_3.Text = 'hour';
            app.hourLabel_2 = uilabel(app.GridLayout2);
            app.hourLabel_2.Text = 'hour';
            app.hourLabel = uilabel(app.GridLayout2);
            app.hourLabel.Text = 'hour';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Cosine curve fit', 'EMD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Cosine curve fit';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '';
            app.TargetcomponentEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TargetcomponentEditField.ValueChangedFcn = createCallbackFcn(app, @TargetcomponentEditFieldValueChanged, true);
            app.TargetcomponentEditField.Value = 24;
            app.TargetcomponentLabel = uilabel(app.GridLayout2);
            app.TargetcomponentLabel.Text = 'Target component:';
            app.HarmonicsEditField = uieditfield(app.GridLayout2, 'text');
            app.HarmonicsEditField.ValueChangedFcn = createCallbackFcn(app, @HarmonicsEditFieldValueChanged, true);
            app.HarmonicsEditField.Value = '[a, b, c, ...]';
            app.HarmonicsLabel = uilabel(app.GridLayout2);
            app.HarmonicsLabel.Text = 'Harmonics:';
            app.MinlengthEditField = uieditfield(app.GridLayout2, 'text');
            app.MinlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinlengthEditFieldValueChanged, true);
            app.MinlengthEditField.Value = '7*24';
            app.MinlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MinlengthEditFieldLabel.Text = 'Min. length:';
            app.NapTab = uitab(app.TabGroup);
            app.NapTab.Title = 'Nap';
            app.GridLayout3 = uigridlayout(app.NapTab);
            app.SummaryreportCheckBox_Nap = uicheckbox(app.GridLayout3);
            app.SummaryreportCheckBox_Nap.ValueChangedFcn = createCallbackFcn(app, @SummaryreportCheckBox_NapValueChanged, true);
            app.SummaryreportCheckBox_Nap.Text = 'Summary report?';
            app.SummaryreportCheckBox_Nap.Value = true;
            app.ModeDropDown = uidropdown(app.GridLayout3);
            app.ModeDropDown.Items = {'Proportional Integrating', 'Not Available'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Proportional Integrating';
            app.ModeDropDownLabel = uilabel(app.GridLayout3);
            app.ModeDropDownLabel.Text = 'Mode';
            app.DaytimeStartEditField = uieditfield(app.GridLayout3, 'text');
            app.DaytimeStartEditField.ValueChangedFcn = createCallbackFcn(app, @DaytimeStartEditFieldValueChanged, true);
            app.DaytimeStartEditField.Value = '9:00';
            app.DaytimeEditFieldLabel = uilabel(app.GridLayout3);
            app.DaytimeEditFieldLabel.Text = 'Daytime:';
            app.DaytimeEndEditField = uieditfield(app.GridLayout3, 'text');
            app.DaytimeEndEditField.ValueChangedFcn = createCallbackFcn(app, @DaytimeEndEditFieldValueChanged, true);
            app.DaytimeEndEditField.Value = '19:00';
            app.Label_8 = uilabel(app.GridLayout3);
            app.Label_8.Text = '--';
            app.SleepTab = uitab(app.TabGroup);
            app.SleepTab.Title = 'Sleep';
            app.GridLayout4 = uigridlayout(app.SleepTab);
            app.SummaryreportCheckBox_Sleep = uicheckbox(app.GridLayout4);
            app.SummaryreportCheckBox_Sleep.ValueChangedFcn = createCallbackFcn(app, @SummaryreportCheckBox_SleepValueChanged, true);
            app.SummaryreportCheckBox_Sleep.Text = 'Summary report?';
            app.SummaryreportCheckBox_Sleep.Value = true;
            app.ModeDropDown_Sleep = uidropdown(app.GridLayout4);
            app.ModeDropDown_Sleep.Items = {'Proportional Integrating', 'Not Available'};
            app.ModeDropDown_Sleep.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_SleepValueChanged, true);
            app.ModeDropDown_Sleep.Value = 'Proportional Integrating';
            app.ModeDropDown_2Label = uilabel(app.GridLayout4);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.SleepStartEditField = uieditfield(app.GridLayout4, 'text');
            app.SleepStartEditField.ValueChangedFcn = createCallbackFcn(app, @SleepStartEditFieldValueChanged, true);
            app.SleepStartEditField.Tooltip = {'Use 00:00--24:00 for 24-hour sleep detection'};
            app.SleepStartEditField.Value = '21:00';
            app.TimerestrictionLabel = uilabel(app.GridLayout4);
            app.TimerestrictionLabel.Text = 'Time restriction:';
            app.SleepEndEditField = uieditfield(app.GridLayout4, 'text');
            app.SleepEndEditField.ValueChangedFcn = createCallbackFcn(app, @SleepEndEditFieldValueChanged, true);
            app.SleepEndEditField.Tooltip = {'Use 00:00--24:00 for 24-hour sleep detection'};
            app.SleepEndEditField.Value = '7:00';
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = '--';
            app.GapTab = uitab(app.TabGroup);
            app.GapTab.Title = 'Gap';
            app.GridLayout5 = uigridlayout(app.GapTab);
            app.minLabel_2 = uilabel(app.GridLayout5);
            app.minLabel_2.Enable = 'off';
            app.minLabel_2.Text = 'min';
            app.minLabel = uilabel(app.GridLayout5);
            app.minLabel.Enable = 'off';
            app.minLabel.Text = 'min';
            app.ManAutoSwitch = uiswitch(app.GridLayout5, 'slider');
            app.ManAutoSwitch.Items = {'Manual', 'Auto'};
            app.ManAutoSwitch.ValueChangedFcn = createCallbackFcn(app, @ManAutoSwitchValueChanged, true);
            app.ManAutoSwitch.Value = 'Manual';
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = '';
            app.StartEditField = uieditfield(app.GridLayout5, 'text');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.GridLayout5);
            app.StartEditFieldLabel.Text = 'Start:';
            app.EndEditField = uieditfield(app.GridLayout5, 'text');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.GridLayout5);
            app.EndEditFieldLabel.Text = 'End:';
            app.ThreEditField = uieditfield(app.GridLayout5, 'text');
            app.ThreEditField.ValueChangedFcn = createCallbackFcn(app, @ThreEditFieldValueChanged, true);
            app.ThreEditField.Enable = 'off';
            app.ThreEditFieldLabel = uilabel(app.GridLayout5);
            app.ThreEditFieldLabel.Enable = 'off';
            app.ThreEditFieldLabel.Text = 'Thre.:';
            app.MinDurEditField = uieditfield(app.GridLayout5, 'text');
            app.MinDurEditField.ValueChangedFcn = createCallbackFcn(app, @MinDurEditFieldValueChanged, true);
            app.MinDurEditField.Enable = 'off';
            app.MinDurEditFieldLabel = uilabel(app.GridLayout5);
            app.MinDurEditFieldLabel.Enable = 'off';
            app.MinDurEditFieldLabel.Text = 'Min. Dur.:';
            app.threUnitDropDown = uidropdown(app.GridLayout5);
            app.threUnitDropDown.Items = {'Min', 'Max', 'Range', 'SD'};
            app.threUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @threUnitDropDownValueChanged, true);
            app.threUnitDropDown.Enable = 'off';
            app.threUnitDropDown.Value = 'Min';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '';
            app.MergeifEditField = uieditfield(app.GridLayout5, 'text');
            app.MergeifEditField.ValueChangedFcn = createCallbackFcn(app, @MergeifEditFieldValueChanged, true);
            app.MergeifEditField.Enable = 'off';
            app.MergeifEditFieldLabel = uilabel(app.GridLayout5);
            app.MergeifEditFieldLabel.Enable = 'off';
            app.MergeifEditFieldLabel.Text = 'Merge if <:';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.circShowArea = uitextarea(app.GridLayout);
            app.circShowArea.Visible = 'off';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '';
            app.TextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.ActigraphycontrolpanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ezActi2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ActigraphycontrolpanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActigraphycontrolpanelUIFigure)
        end
    end
end