classdef ezActi3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActigraphycontrolpanelUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportWizardMenu                matlab.ui.container.Menu
        OptionMenu                      matlab.ui.container.Menu
        GraphMenu                       matlab.ui.container.Menu
        ActogramMenu                    matlab.ui.container.Menu
        PolarPlotMenu                   matlab.ui.container.Menu
        ISMenu                          matlab.ui.container.Menu
        AdvancedMenu                    matlab.ui.container.Menu
        DetrendedfluctuationanalysisDFAMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutezActiMenu                 matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        AccelerometertoActivityCountsPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        AdvancedButton                  matlab.ui.control.Button
        FsEditField                     matlab.ui.control.EditField
        FsEditFieldLabel                matlab.ui.control.Label
        EpochEditField                  matlab.ui.control.EditField
        EpochEditFieldLabel             matlab.ui.control.Label
        UnitDropDown                    matlab.ui.control.DropDown
        UnitDropDownLabel               matlab.ui.control.Label
        EndEditField_2                  matlab.ui.control.EditField
        EndEditField_2Label             matlab.ui.control.Label
        ApplytoAllFilesCheckBox         matlab.ui.control.CheckBox
        StartEditField_2                matlab.ui.control.EditField
        StartEditField_2Label           matlab.ui.control.Label
        ZColumnDropDown                 matlab.ui.control.DropDown
        ZColumnDropDownLabel            matlab.ui.control.Label
        YColumnDropDown                 matlab.ui.control.DropDown
        YColumnDropDownLabel            matlab.ui.control.Label
        XColumnDropDown                 matlab.ui.control.DropDown
        XColumnDropDownLabel            matlab.ui.control.Label
        TimeColumnDropDown              matlab.ui.control.DropDown
        TimeColumnDropDownLabel         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Label_10                        matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        circShowArea                    matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        TabGroup                        matlab.ui.container.TabGroup
        GapTab                          matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MergeifEditFieldLabel           matlab.ui.control.Label
        MergeifEditField                matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        threUnitDropDown                matlab.ui.control.DropDown
        MinDurEditFieldLabel            matlab.ui.control.Label
        MinDurEditField                 matlab.ui.control.EditField
        ThreEditFieldLabel              matlab.ui.control.Label
        ThreEditField                   matlab.ui.control.EditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.EditField
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        ManAutoSwitch                   matlab.ui.control.Switch
        minLabel                        matlab.ui.control.Label
        minLabel_2                      matlab.ui.control.Label
        CircadianTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        CosinorTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        CIalphaEditField                matlab.ui.control.EditField
        CIalphaEditFieldLabel           matlab.ui.control.Label
        MinlengthEditFieldLabel         matlab.ui.control.Label
        MinlengthEditField              matlab.ui.control.EditField
        HarmonicsLabel                  matlab.ui.control.Label
        HarmonicsEditField              matlab.ui.control.EditField
        TargetcomponentLabel            matlab.ui.control.Label
        TargetcomponentEditField        matlab.ui.control.NumericEditField
        hourLabel                       matlab.ui.control.Label
        hourLabel_2                     matlab.ui.control.Label
        hourLabel_3                     matlab.ui.control.Label
        NPTab                           matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        hourLabel_4                     matlab.ui.control.Label
        PeriodEditField                 matlab.ui.control.EditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        minLabel_3                      matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.EditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        dayLabel                        matlab.ui.control.Label
        FixLenghEditField               matlab.ui.control.NumericEditField
        FixLenghEditFieldLabel          matlab.ui.control.Label
        EMDTab                          matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TargetcomponentLabel_2          matlab.ui.control.Label
        MinlengthLabel                  matlab.ui.control.Label
        emdTargetComp                   matlab.ui.control.NumericEditField
        emdMinLen                       matlab.ui.control.EditField
        hourLabel_6                     matlab.ui.control.Label
        hourLabel_5                     matlab.ui.control.Label
        SleepTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        OptionDropDown                  matlab.ui.control.DropDown
        OptionDropDownLabel             matlab.ui.control.Label
        WakethreDropDown                matlab.ui.control.DropDown
        WakethreDropDownLabel           matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        SleepEndEditField               matlab.ui.control.EditField
        TimerestrictionLabel            matlab.ui.control.Label
        SleepStartEditField             matlab.ui.control.EditField
        AlgorithmLabel                  matlab.ui.control.Label
        ModeDropDown_Sleep              matlab.ui.control.DropDown
        Status                          matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        previewUITable                  matlab.ui.control.Table
        DoButton                        matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        WriteButton                     matlab.ui.control.Button
        CropButton                      matlab.ui.control.Button
        InterpButton                    matlab.ui.control.Button
        FlipButton                      matlab.ui.control.Button
        AutoButton                      matlab.ui.control.Button
    end


    properties (Access = public, Hidden = true)
        % configuration
        config = struct('RunMode', {'Gap'}, ...
            'ExistedFlag', 0, ...
            'ExistedExtension', {''}, ...
            'Gap', 0, ...
            'GapExtension', {''}, ...
            'Actogram', 0, ...
            'PolarClock', 0, ...
            'SummaryReport', 0, ...
            'Figures', 0, ...
            'FigureExt', '.jgp', ...
            'TimeParserMode', {'None'}, ...
            ...
            'TimeParserProperties', ...
            struct('SameDateTime', '', ...
            'DateTimeFileLocation', '', ...
            'DatetimeInd', 1, ...
            'DatetimeFormat', 'yyyyMMddHHmmss'), ...
            ...
            'EpochParserMode', {'None'}, ...
            ...
            'EpochParserProperties', ...
            struct('SameEpoch', 15, ...
            'EpochFileLocation', '', ...
            'EpochInd', 1), ...
            ...
            'FileNameSeparator', '_', ...
            'RawSetting', ...
                struct('NonMovingThreshold', 0.01, ...
                'NonMovingDuration', 10, ...
                'BackgroundNoise', 0.02, ...
                'PlotCalibration', 1, ...
                'PlotAccCounts', 1))

        % data access
        filePath                        % data dir
        fileList                        % all list files
        importedEpi = 0
        importedEpiSuffix
        importedGap = 0                 % to always carry gaps over to ease the process of other modes
        importedGapSuffix               % if Mode = 'Gap', they are the same to importedEpi and importedEpiSuffix

        startTimeFileInfo

        % gap specific
        gap = struct('matchFile', '', ...
            'parameter', struct('method', 'Manual', 'startIndex', nan, 'endIndex', nan, 'threshold', [], 'thresholdUnit', 'Min', 'minDuration', [], 'minDistance', []), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 0, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over

        % circadian specific
        circadian = struct('matchFile', '', ...
            'parameter', struct('method', 'Cosine curve fit', 'target', 24, 'harmonic', [], 'minLength', 7*24, 'ci', 0.05, 'UserData', [], 'fixlength', 5, 'scale', 60), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...              % reserved field
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over

        circadianTemp = struct('is', [], 'period', []);

        % nap specific
        nap = struct('matchFile', '', ...
            'parameter', struct('mode', {'Proportional integrating'}, 'modeParameter', struct('P', 0.001, 'V', [106 54 58 76 230 74 67]./2, 'C', 0), 'startTime', '9:00', 'endTime', '19:00'), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []))                 % 5/14/2021 supports gap carry over

        % sleep specific
        sleep = struct('matchFile', '', ...
            'parameter', struct('method', {'Cole-Kripke'}, ...
            'mode', {'Proportional integrating'}, ...
            'modeParameter', struct('P', 0.001, 'V', [106 54 58 76 230 74 67]./2, 'C', 0, 'T', 40), ...
            'option', {'Nocturnal'}, ...
            'startTime', '21:00', 'endTime', '7:00'), ...
            'data', [], ...
            'handle', [], ...
            'text', [], ...
            'report', 1, ...
            'gap', struct('data', [], ...
            'handle', [], ...
            'text', []));                % 5/14/2021 supports gap carry over

        % raw specific
        raw = struct('matchFile', '', ...
            'Time', 1, ...
            'X', 2, ...
            'Y', 3, ...
            'Z', 4, ...
            'StartTime', '19:00', ...
            'EndTime', '06:00', ...
            'Unit', 'milli-g', ...
            'Fs', 25, ...
            'Epoch', 30, ...
            'data', [], ...
            'firstDay', [], ...
            'Apply2All', 0);

        % plot spec
        actigraphyApp
        yrange
        actogramApp
        polarPlotApp
        isApp

        % runtime
        startIndex
        pointer = 'current'

        % switch
        dataDefined  = 0
        timeDispMode = 0 % time display mode: 0-disabled, 1-from file, 2-from file name, 3-all the same from input
        dataShown    = 0
    end

    properties (Access = public, Hidden = true)
        % ezActi2 data
        % data2 should be an acti object
        % however, acti.Sleep and acti.Gap will not be set
        % when loaded, although sometimes predefined results
        % are to be loaded when config'ed
        % this is to dissociate this to the sleep/gap struct
        % of the ezActi2 object
        % so temporally, still use ezActi plot scheme (the @acti.plot
        % need some updates later)
        data2
        dataLoaded = 0

        message = ''

        % data parameter
        x
        data
        dataImputed
        epoch = 15
        startTime

        % runtime
        mode
        currentFileName
        currentFileNameNoExt
        currentFileNameFmt

        % figure handlers
        gSig
        gImpute

        % gap specific
        gapImputationCrit
        gapImputationWindow
        gapImputationMethod = 'Centered mean'
        gapImputationSave = 1

        % switch
        timeSet = 0
    end

    methods (Access = private)
        function statusFeed(app, msg, varargin)
            color = 'k';
            if nargin == 3
                if ~ischar(varargin{1}) && numel(varargin{1}) == 3
                    color = varargin{1};
                else
                    switch varargin{1}
                        case 'warn'
                            color = [1 .325 .286];
                        case {'error', 'r'}
                            color = 'r';
                    end
                end
            end
            if nargin == 4
                oldStatus = string(varargin{2});
            else
                oldStatus = "";
            end
            app.Status.Text      = oldStatus + msg;
            app.Status.FontColor = color;
        end

        function enable(app, event)
            set(event, 'Enable', 'on');
        end

        function disable(app, event)
            set(event, 'Enable', 'off');
        end

        function resetControl(app, event, runMode)
            switch runMode
                case 'Circadian'
                    app.circShowArea.Value    = '';
                    app.circadian.data        = [];
                    app.previewUITable.Data   = [];
                case 'Gap'
                    app.StartEditField.Value  = '';
                    app.EndEditField.Value    = '';
                    app.previewUITable.Data   = [];
                    app.gap.data = [];
            end
        end

        function dataShow(app)
            app.yrange = [min(app.data(:, end)) max(app.data(:, end))];
            app.yrange = [app.yrange(1) - .1*diff(app.yrange), app.yrange(2) + .1*diff(app.yrange)];

            % signal end bar
            plot(app.actigraphyApp.actiAxis, [app.x(end), app.x(end)], [-1e6 1e6], 'LineWidth', 3, 'Color', 'c');
            set(app.actigraphyApp.actiAxis, 'NextPlot', 'add');

            % plot signal
            app.gSig    = plot(app.actigraphyApp.actiAxis, app.x, app.data(:, end), 'Color', 'k');

            % plot day seperator
            PointPerDay = 24*60*60 / app.epoch;
            TotalDays   = ceil(length(app.x) / PointPerDay);
            StartBorder = (1:TotalDays-1) .* PointPerDay + 1;
            Seperator   = repmat([-1e6; 1e6], 1, length(StartBorder));
            plot(app.actigraphyApp.actiAxis, [StartBorder; StartBorder], Seperator, 'Color', [.15 .15 .65], 'LineStyle', '--', 'LineWidth', 2);
            xlabel(app.actigraphyApp.actiAxis, 'Points');

            % range set
            if app.yrange(2) > app.yrange(1) % to avoid nan
                app.actigraphyApp.actiAxis.YLim    = app.yrange;
            end
            app.actigraphyApp.actiAxis.XLim    = [0 length(app.x)];
            app.actigraphyApp.shallowAxis.XLim = [0 length(app.x)];
            zoom('reset');

            % display time
            switch app.timeDispMode
                case 1
                    [~, fileID] = fileparts(app.currentFileName);
                    if any(string(app.startTimeFileInfo.FileID) == string(fileID))
                        app.timeSet   = 1;
                        app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
                    else
                        app.timeSet   = 0;
                    end
                case 2
                    nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                    app.startTime = datetime(...
                        nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
                        'InputFormat', ...
                        app.config.TimeParserProperties.DatetimeFormat);
                    app.timeSet   = 1;
            end

            if app.timeSet
                app.actigraphyApp.actiAxis.Box        = 'off';
                app.actigraphyApp.shallowAxis.Visible = 'on';

                peerXTick = app.actigraphyApp.actiAxis.XTick;
                xTick     = app.startTime + seconds(app.epoch).*peerXTick - app.x(1).*seconds(app.epoch);
                app.actigraphyApp.shallowAxis.XTick = peerXTick;
                app.actigraphyApp.shallowAxis.XTickLabel = datestr(xTick, 'dd-mmm-yyyy HH:MM');
                app.actigraphyApp.shallowAxis.XTickLabelRotation = 10;
            else
                app.actigraphyApp.shallowAxis.Visible = 'off';
                app.actigraphyApp.actiAxis.Box        = 'on';
            end

            % show toolbar
            axtoolbar(app.actigraphyApp.actiAxis, {'export', 'zoomin', 'zoomout', 'restoreview', 'pan', 'datacursor'});
            app.actigraphyApp.shallowAxis.Toolbar.Visible = 'off';
            app.actigraphyApp.actiAxis.Toolbar.Visible    = 'on';

            %             function [datetimestr, format] = parsetime(str, format)
            %                 % PARSETIME parse date time from string using format
            %                 %
            %                 % $Author: Peng Li
            %                 % $Date:   Apr. 5, 2021
            %                 %
            %
            %                 ind = strfind(format, '*');
            %
            %                 if numel(ind) == 1
            %                     if ind == 1
            %                         datetimestr = str(end-length(format)+1+1:end);
            %                     else
            %                         datetimestr = str(1:ind-1);
            %                     end
            %                 else
            %                     datetimestr = str(setxor(1:length(str), ind));
            %                 end
            %
            %                 format = format(setxor(1:length(format), ind));
            %             end
        end

        function addItem(app, event, item)
            iG = length(app.(lower(app.mode)).handle) + 1;
            app.(lower(app.mode)).handle(iG) = patch(app.actigraphyApp.actiAxis, 'XData', ...
                [item(1) item(1) item(2) item(2)], ...
                'YData', [app.yrange(1) app.yrange(2) app.yrange(2) app.yrange(1)], ...
                'FaceColor', 'm', 'EdgeColor', 'm', 'FaceAlpha', 0.2);
            app.(lower(app.mode)).text(iG) = text(item(1), app.yrange(2) - ((iG-1)./size(app.(lower(app.mode)).data, 1))*diff(app.yrange), ...
                [app.mode ': # ' num2str(iG)], 'Color', 'm');

            % switch layers
            child = app.actigraphyApp.actiAxis.Children;
            child = [child(end); child(1:end-1)];
            app.actigraphyApp.actiAxis.Children = child;
        end

        function updateItem(app)
            epi    = app.(lower(app.mode)).data;
            Yrange = app.yrange;

            % update plot
            if ~isempty(app.(lower(app.mode)).handle)
                delete(app.(lower(app.mode)).handle);
                app.(lower(app.mode)).handle = [];
            end

            if ~isempty(app.(lower(app.mode)).text)
                delete(app.(lower(app.mode)).text);
                app.(lower(app.mode)).text = [];
            end

            if ~strcmpi(app.mode, 'circadian')
                % update Gap Table on Face
                app.previewUITable.Data = epi;

                for iG = 1:size(epi, 1)
                    app.(lower(app.mode)).handle(iG) = patch('Parent', app.actigraphyApp.actiAxis, 'XData', [epi(iG, 1) epi(iG, 1) epi(iG, 2) epi(iG, 2)], ...
                        'YData', [Yrange(1) Yrange(2) Yrange(2) Yrange(1)], 'FaceColor', 'm', 'EdgeColor', 'm', 'FaceAlpha', 0.2);
                    app.(lower(app.mode)).text(iG) = text(app.actigraphyApp.actiAxis, epi(iG, 1), Yrange(2) - ((iG-1)./size(epi, 1))*diff(Yrange), [app.mode ': # ' num2str(iG)], 'Color', 'm');
                end


                % switch layers
                child = app.actigraphyApp.actiAxis.Children;
                child = [child(end-length(app.gImpute):end); child(1:end-length(app.gImpute)-1)];
                app.actigraphyApp.actiAxis.Children = child;
            else
                app.(lower(app.mode)).handle = plot(app.actigraphyApp.actiAxis, app.x, epi, 'r', 'LineWidth', 2);
            end

            drawnow;
        end

        function gapImputation(app)
            toImputeGapIndex = find(app.(lower(app.mode)).data(:, 2)-app.(lower(app.mode)).data(:, 1) <= app.gapImputationCrit);
            imputationMarker = ones(size(toImputeGapIndex))&1;

            if isempty(toImputeGapIndex)
                app.statusFeed('Caution: no gaps meet criteria. No imputation done.', 'warn');
                return;
            end

            if ~isempty(app.gImpute)
                delete(app.gImpute);
                app.gImpute = [];
            end

            data_ = app.data;
            gaps  = ~gap2Series(app.(lower(app.mode)).data, length(data_));
            data_(gaps) = nan;

            iG2 = 1;
            for iG = 1:length(toImputeGapIndex)
                cx = app.x(app.(lower(app.mode)).data(toImputeGapIndex(iG), 1):app.(lower(app.mode)).data(toImputeGapIndex(iG), 2));
                if cx(1) == 1 || cx(2) == length(app.x)
                    imputationMarker(iG) = 0&0;
                    continue;
                else
                    imputationWindLen = max(ceil((app.gapImputationWindow - length(cx))/2), 1);
                    extCx = [max(1, cx(1)-imputationWindLen):cx(1)-1 cx(end)+1:min(length(app.x), cx(end)+imputationWindLen)];

                    extCy = [data_(extCx(1):cx(1)-1); data_(cx(end)+1:extCx(end))];

                    switch app.gapImputationMethod
                        case 'Centered mean'
                            app.dataImputed(cx) = nanmean(extCy);
                        case 'Linear interp'
                            app.dataImputed(cx) = interp1(extCx, extCy, cx, 'linear');
                    end

                    app.gImpute(iG2) = plot(app.actigraphyApp.actiAxis, cx, app.dataImputed(cx), ...
                        'Color', 'g', 'LineWidth', 2, 'Marker', '.');
                    iG2 = iG2 + 1;
                end
            end

            % delete gaps where imputation is done
            app.(lower(app.mode)).data(toImputeGapIndex(imputationMarker), :) = [];
            app.updateItem;
            app.statusFeed('Data imputed and gaps info updated.');

            % save data or not
            if app.gapImputationSave
                savePt = fullfile(app.filePath, 'Imputed');
                if ~(exist(savePt, 'dir') == 7)
                    mkdir(savePt);
                end

                fid = fopen(fullfile(savePt, [app.currentFileName(1:end-4) '.imputed']), 'w');
                fprintf(fid, '%f\r', app.dataImputed);
                fclose(fid);

                if ~isempty(app.(lower(app.mode)).data)
                    gapPt = fullfile(savePt, 'Gap');
                    if ~(exist(gapPt, 'dir') == 7)
                        mkdir(gapPt);
                    end
                    fid = fopen(fullfile(gapPt, [app.currentFileName(1:end-4) '_imputed.gap']), 'w');
                    fprintf(fid, '%d,%d\r', app.(lower(app.mode)).data');
                    fclose(fid);
                end
            end
            app.statusFeed('Data imputed, gaps info updated, and imputed data saved.');
        end

        function doGap(app)
            switch app.gap.parameter.method
                case 'Manual'
                    if isnan(app.gap.parameter.startIndex) || isnan(app.gap.parameter.endIndex)
                        app.statusFeed('Either start or end index invalid!', 'r');
                        return;
                    end

                    if app.gap.parameter.endIndex < app.gap.parameter.startIndex
                        app.statusFeed('Manual define error: start index should be smaller than end index!', 'r');
                        return;
                    end

                    epiRec = [app.gap.parameter.startIndex app.gap.parameter.endIndex];

                    if ~isempty(app.(lower(app.mode)).data) && ~isempty(intersect(app.(lower(app.mode)).data, epiRec, 'rows', 'stable'))
                        app.statusFeed('Caution: already defined episode; not added!', [1 .325 .286]);
                        return;
                    end

                    if epiRec(2) > length(app.data)
                        app.statusFeed('Caution: episode updated. End index larger than data length! Forced to be equal to data length by the app.', 'warn');
                        epiRec(2) = length(app.data);
                    end

                    app.(lower(app.mode)).data = [app.(lower(app.mode)).data; epiRec];

                    [~, ind] = sort(app.(lower(app.mode)).data(:, 1));
                    app.(lower(app.mode)).data = app.(lower(app.mode)).data(ind, :);

                    % update preview
                    app.previewUITable.Data  = app.(lower(app.mode)).data;

                    % add item on plot
                    addItem(app, [], epiRec);

                    % reset detectables
                    app.StartEditField.Value     = '';
                    app.gap.parameter.startIndex = nan;
                    app.EndEditField.Value       = '';
                    app.gap.parameter.endIndex   = nan;

                    % update the order of plot and text
                    % accordingly
                    app.(lower(app.mode)).handle = app.(lower(app.mode)).handle(ind);
                    app.(lower(app.mode)).text   = app.(lower(app.mode)).text(ind);

                    % update text display
                    for iT = 1:length(app.(lower(app.mode)).text)
                        set(app.(lower(app.mode)).text(iT), 'String', [app.mode ': # ' num2str(iT)], 'Color', 'm');
                    end

                    if epiRec(2) == app.gap.parameter.endIndex
                        app.statusFeed('Episode updated.');
                    end
                case 'Auto'
                    if ~(isempty(app.gap.data))
                        delete(app.gap.handle); app.gap.handle = [];
                        delete(app.gap.text); app.gap.text = [];
                        app.gap.data = [];
                    end

                    rec = app.data;

                    switch app.gap.parameter.thresholdUnit
                        case 'Min'
                            gapseries1 = rec <= app.gap.parameter.threshold;
                        case 'Max'
                            gapseries1 = rec >= app.gap.parameter.threshold;
                        case 'Range'
                            gapseries1 = rec <= app.gap.parameter.threshold(1) | rec >= app.gap.parameter.threshold(2);
                        case 'SD'
                            lmean = mean(rec); thre = app.gap.parameter.threshold.*std(rec);
                            gapseries1 = (abs(rec - lmean) >= thre) & 0; % to update
                    end

                    % 2. duration criterion
                    if any(gapseries1)
                        gapTemp = detConstantOne(gapseries1);
                        gapTemp(gapTemp(:, 2) - gapTemp(:, 1) < app.gap.parameter.minDuration*60/app.epoch, :) = [];

                        gapseries2 = ~gap2Series(gapTemp, length(rec));

                        % enable adapting in nan
                        gapseries2 = gapseries2 | isnan(rec);

                        % 3. merge
                        segs = transSegGap(detConstantOne(gapseries2), length(rec));
                        merg = segs(:, 2) - segs(:, 1) <= app.gap.parameter.minDistance*60/app.epoch;
                        segs(merg, :) = [];

                        % auto det will clear all cache gaps
                        if ~ (size(segs, 1) == 1 && all(segs == [1, length(rec)]))
                            gapTemp  = transSegGap(segs, length(rec));
                            gapTemp  = unique(gapTemp, 'rows');
                            [~, ind] = sort(gapTemp(:, 1));
                            app.(lower(app.mode)).data = gapTemp(ind, :);
                        end
                    else
                        app.(lower(app.mode)).data = [];
                    end

                    % 4. update Face
                    app.updateItem;
                    app.statusFeed('Auto detection done!')
            end

            % propogate to data2.Gap 11/11/2022
            app.data2.Gap = app.gap.data;
        end

        function doCircadian(app)
            if ~isempty(app.(lower(app.mode)).handle)
                delete(app.(lower(app.mode)).handle);
                app.(lower(app.mode)).handle = [];
                try delete(app.(lower(app.mode)).text); end
                app.(lower(app.mode)).text = [];
            end

            switch app.circadian.parameter.method
                case 'Cosine curve fit'
                    if length(app.dataImputed)*app.epoch/3600 < app.circadian.parameter.minLength
                        app.statusFeed('Caution: min. length not met. Do nothing.', 'warn');
                        return;
                    end

                    if length(app.dataImputed)*app.epoch/3600 < app.circadian.parameter.minLength / 24 * app.circadian.parameter.target
                        app.statusFeed('Caution: check target component. data length not supported to fit to the specified component. Do nothing.', 'warn');
                        return;
                    end

                    % [app.circadian.data, pValue, rSquare, component, verbose] = CosAna(app.dataImputed, tInSec, [app.circadian.parameter.target app.circadian.parameter.harmonic]);

                    % Nov 10, 2022
                    % adapt to cosinor class
                    % input time in sec is relative to StartTime in cosinor
                    % class

                    fitFreq = [app.circadian.parameter.target app.circadian.parameter.harmonic];
                    app.data2.CosinorInfo = struct('HarmonicsInHour', fitFreq, 'MinimumLengthInDays', app.circadian.parameter.minLength/24, 'CIAlpha', app.circadian.parameter.ci);
                    app.data2 = app.data2.cosinorActi;

                    c = app.data2.CosinorInfo.UserData;
                    app.circadian.parameter.UserData = c;

                    if app.timeSet
                        c.StartDateTime = app.startTime;
                    end

                    c = c.fit;
                    app.circadian.data = c.DataFitted;
                    pValue  = c.pValue;
                    rSquare = c.R2;

                    c = c.ci;

                    verbose = c.Verbose;                

                    if app.timeSet
                        verbose = [verbose sprintf('\n%s\n', 'Using mid-night (12:00am) as 0°')];
                    else
                        verbose = [verbose sprintf('\n%s\n', 'Using start of recording as 0°')];
                    end

                    verbose = [verbose sprintf('\np value: %.6f\n', pValue) sprintf('R squared: %.6f\n', rSquare)];

                    app.circShowArea.Value = verbose;

                    % enable menu item
                    app.PolarPlotMenu.Enable = 'on';

                    % show data
                    app.(lower(app.mode)).handle = plot(app.actigraphyApp.actiAxis, app.x, app.circadian.data, 'r', 'LineWidth', 2);

                    % polar clock
                    if app.config.PolarClock
                        try
                            close(app.polarPlotApp);
                        end

                        ss = get(0, 'ScreenSize');
                        sa = ss(4);
                        if ss(3) < sa
                            sa = ss(3);
                        end
                        sa = round(sa / 2);

                        app.polarPlotApp = uifigure('Units', 'pixels', 'Position', [ss(3)-1.3*sa ss(4)-1.3*sa sa sa], 'Color', 'w', 'Tag', 'PolarFig');
                        ax = axes(app.polarPlotApp);
                        c = c.plot(ax);
                        app.polarPlotApp.Name = "Polar: " + app.currentFileName;
                    end
                case 'Nonparametric'
                    if length(app.dataImputed)*app.epoch/3600/24 < app.circadian.parameter.fixlength
                        app.statusFeed('Caution: min. length not met. Do nothing.', 'warn');
                        return;
                    end

                    dataNan = app.data2.Data;
                    dataNan(app.data2.GapSeries) = nan;

                    % IS IV
                    nonparaTbl = cell(numel(app.circadian.parameter.scale), 1);
                    isAll  = nonparaTbl;
                    for iT = 1:numel(app.circadian.parameter.scale)
                        scale = app.circadian.parameter.scale(iT);

                        [is, iv, current_period, perc_missing_data, perc_missing_bin] = ...
                            isiv(dataNan, app.epoch/60, scale, app.circadian.parameter.target, app.circadian.parameter.fixlength);
                        isAll{iT} = is(:)';

                        % find max IS to determine the best period
                        [ismax, maxind] = max(is);

                        % render output
                        tbl = table(ismax, iv(maxind), current_period(maxind), perc_missing_data(maxind), perc_missing_bin(maxind), ...
                            'VariableNames', {'is', 'iv', 'period', 'perc_missing_data', 'perc_missing_bin'});

                        nonparaTbl{iT} = tbl;
                    end

                    isAll = vertcat(isAll{:})';
                    if ~isempty(isAll) && ~isscalar(app.circadian.parameter.target)
                        app.circadianTemp.is     = isAll;
                        app.circadianTemp.period = current_period;
                        app.ISMenu.Enable        = 'on';
                    else
                        app.circadianTemp.is     = [];
                        app.circadianTemp.period = [];
                        app.ISMenu.Enable        = 'off';
                    end

                    npTbl = vertcat(nonparaTbl{:});

                    % M10 L5
                    app.data2 = app.data2.m10l5Analysis;
                    m10l5Tbl  = app.data2.M10L5Summary;

                    % combine
                    npTbl = [npTbl repmat(m10l5Tbl, height(npTbl), 1)];

                    if ~isempty(npTbl)
                        npTbl.scale = app.circadian.parameter.scale(:);
                        npTbl = movevars(npTbl, "scale", "Before", 1);
                    else
                        npTbl = array2table(nan(1, 10), 'VariableNames', {'scale', 'is', 'iv', 'period', 'perc_missing_data', 'perc_missing_bin', 'm10', 'm10_mid_time', 'l5', 'l5_mid_time'});
                    end

                    app.circadian.parameter.UserData = ...
                        [table(repmat(string(app.currentFileName), height(npTbl), 1), 'VariableNames', {'filename'}) npTbl];
                    app.previewUITable.Data = npTbl;
                    app.previewUITable.ColumnName = npTbl.Properties.VariableNames;
                case 'EMD'
                    if length(app.dataImputed)*app.epoch/3600 < app.circadian.parameter.minLength
                        app.statusFeed('Caution: min. length not met. Do nothing.', 'warn');
                        return;
                    end

                    if length(app.dataImputed)*app.epoch/3600 < app.circadian.parameter.minLength / 24 * app.circadian.parameter.target
                        app.statusFeed('Caution: check target component. data length not supported to fit to the specified component. Do nothing.', 'warn');
                        return;
                    end

                    fs = 1 / app.epoch; % Hz
                    fc = 1 / app.circadian.parameter.target / 3600;

                    imf = li_upmemd(app.dataImputed, fs, fc);
                    app.circadian.data = imf(:, end) + mean(app.dataImputed);

                    [cycleStart, cycleLength, cycleAmplitude] = ...
                        li_upmemd_cycle(imf(:, end), fs*3600); % fs*3600 becomes 1/hr

                    minCycle = numel(cycleStart); % this may later be changed to an input item

                    % request output
                    meanAmplitude = mean(cycleAmplitude(1:minCycle), 'omitnan');
                    sdAmplitude   = std(cycleAmplitude(1:minCycle), 'omitnan');

                    meanPeriod    = mean(cycleLength(1:minCycle), 'omitnan');
                    sdPeriod      = std(cycleLength(1:minCycle), 'omitnan');

                    if app.timeSet
                        staTime = datenum(app.startTime);
                        verbose = sprintf('%s\n', 'Phase is in actual time.');
                    else
                        % fake start time
                        staTime = 0;
                        verbose = sprintf('%s\n', 'Phase is w.r.t. 00:00:00 as start time.');
                    end
                    endTime = (length(app.dataImputed)-1)*app.epoch / (3600*24) + staTime;
                    t       = linspace(staTime, endTime, length(app.dataImputed))';

                    peakTime      = datetime(t(cycleStart), 'ConvertFrom', 'datenum');
                    phaseInHour   = hour(peakTime) + minute(peakTime)/60 + second(peakTime)/3600;
                    meanPhase     = mean(phaseInHour, 'omitnan');
                    sdPhase       = std(phaseInHour, 'omitnan');

                    cycleSD       = std(app.dataImputed(1:ceil(sum(cycleLength(1:minCycle))*3600/app.epoch)));

                    verbose = [verbose sprintf('\nTotal %d cycles detected (the first < 1/4 cycle is removed)', minCycle)];

                    verbose = [verbose ...
                        sprintf('\n\nThe cycle lengths are (in hour):\n') ...
                        sprintf('\t%s\n', string(cycleLength)) ...
                        sprintf('\n\nThe phases are (in hour):\n') ...
                        sprintf('\t%s\n', string(phaseInHour)) ...
                        sprintf('\n\nThe cycle amplitudes are:\n') ...
                        sprintf('\t%s\n', string(cycleAmplitude))];


                    res = table(meanAmplitude, sdAmplitude, meanPeriod, sdPeriod, meanPhase, sdPhase, cycleSD);
                    res = [table({app.currentFileName}, 'VariableNames', {'filename'}) res];

                    app.circShowArea.Value = verbose;

                    % show data
                    app.(lower(app.mode)).handle = plot(app.actigraphyApp.actiAxis, app.x, app.circadian.data, 'r', 'LineWidth', 2);
            end

            % summary
            if app.config.SummaryReport
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end

                switch app.circadian.parameter.method
                    case 'Cosine curve fit'
                        if ~(exist(fullfile(writePt, 'report.COSINOR'), 'file') == 2)
                            fid = fopen(fullfile(writePt, 'report.COSINOR'), 'w');
                            fprintf(fid, '%s,%s,%s,%s,%s,%s,%s,%s', 'filename', 'mean', 'sd', 'mesor', 'mesor_lb', 'mesor_rb', 'pvalue', 'rsquared');
                            for iH = 1:length(fitFreq)
                                fprintf(fid, ',%s,%s,%s,%s,%s,%s,%s,%s,%s', ...
                                    "amplitude"+string(fitFreq(iH)), "amplitude"+string(fitFreq(iH))+"_lb", "amplitude"+string(fitFreq(iH))+"_rb", ...
                                    "acrophase"+string(fitFreq(iH)), "acrophase"+string(fitFreq(iH))+"_lb", "acrophase"+string(fitFreq(iH))+"_lb", ...
                                    "acrophase"+string(fitFreq(iH))+"_in_hour", "acrophase"+string(fitFreq(iH))+"_in_hour_lb", "acrophase"+string(fitFreq(iH))+"_in_hour_rb");
                            end
                            fprintf(fid, '\r\n');
                        else
                            fid = fopen(fullfile(writePt, 'report.COSINOR'), 'a');
                        end

                        fprintf(fid, '%s,%f,%f,%f,%f,%f,%f,%f', app.currentFileName, mean(app.dataImputed, 'omitnan'), std(app.dataImputed, 'omitnan'), c.Mesor, c.MesorCI(1), c.MesorCI(2), pValue, rSquare);
                        for iH = 1:length(fitFreq)
                            fprintf(fid, ',%f,%f,%f,%f,%f,%f', c.Amplitude(iH), c.AmplitudeCI(iH, 1), c.AmplitudeCI(iH, 2), c.Acrophase(iH), c.AcrophaseCI(iH, 1), c.AcrophaseCI(iH, 2));
                            phase_in_hour    = c.Acrophase(iH)       / 360 * fitFreq(iH); % modif. 7.12.2021 instead of theta/15, do theta*360/P to reflect the actual period % 11.10/22 should be theta / 360 * P
                            phase_in_hour_CI = c.AcrophaseCI(iH, :) ./ 360 * fitFreq(iH);

                            fprintf(fid, ',%f,%f,%f', phase_in_hour, phase_in_hour_CI(1), phase_in_hour_CI(2));
                        end
                        fprintf(fid, '\r\n');
                        fclose(fid);
                    case 'Nonparametric'
                        if ~(exist(fullfile(writePt, 'report.NP'), 'file') == 2)
                            writetable(app.circadian.parameter.UserData, fullfile(writePt, 'report.NP'), ...
                                'FileType', 'text', 'WriteVariableNames', 1);
                        else
                            % Mar 27, 2023
                            % need to disable writetable to tolerate older MATLAB
                            writetable(app.circadian.parameter.UserData, fullfile(writePt, 'report.NP'), ...
                                'FileType', 'text', 'WriteVariableNames', 0, 'WriteMode', 'append');
                        end
                    case 'EMD'
                        if ~(exist(fullfile(writePt, 'report.EMD'), 'file') == 2)
                            writetable(res, fullfile(writePt, 'report.EMD'), ...
                                'FileType', 'text', 'WriteVariableNames', 1);
                        else
                            % Mar 27, 2023
                            % disable writetable to tolerate older MATLAB
                            % writetable(res, fullfile(writePt, 'report.EMD'), ...
                            %     'FileType', 'text', 'WriteVariableNames', 0, 'WriteMode', 'append');
                            fid = fopen(fullfile(writePt, 'report.EMD'), 'a');
                            fprintf(fid, '%s,%f,%f,%f,%f,%f,%f,%f\r\n', res.filename{1}, res{:, 2:end});
                            fclose(fid);
                        end
                end

                app.statusFeed('Circadian component estimated and saved.');
            else
                app.statusFeed('Circadian component estimated.');
            end
        end

        % this function will retire
        function doNap(app)
            if ~(isempty(app.nap.data))
                delete(app.nap.handle); app.nap.handle = [];
                delete(app.nap.text); app.nap.text = [];
                app.nap.data = [];
            end

            % daytime mask
            dayMask = doMask2(length(app.data), app.epoch, app.startTime, app.nap.parameter.startTime, app.nap.parameter.endTime);

            % sleep detection
            sleepSeries = doSleepDet(app.data, app.epoch, app.nap.parameter.modeParameter.V, app.nap.parameter.modeParameter.P, app.nap.parameter.modeParameter.C, 2);

            temp   = sleepSeries & dayMask;
            napEpi = detConstantOne(temp);

            % napping: >=5 min otherwise discarded
            if ~isempty(napEpi)
                napEpi(napEpi(:, 2) - napEpi(:, 1) < 5 * 60 / app.epoch, :) = [];
            end

            app.nap.data = napEpi;

            % update Face
            app.updateItem;
            app.statusFeed('Nap detection done!')

            % summary
            if app.nap.report
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end

                if ~(exist(fullfile(writePt, 'report.NAP'), 'file') == 2)
                    fid = fopen(fullfile(writePt, 'report.NAP'), 'w');
                    fprintf(fid, '%s,%s,%s,%s,%s\r', 'filename', 'nap_duration_in_min_per_day', 'nap_frequency_per_day', 'nap_timing', 'activity_length');
                else
                    fid = fopen(fullfile(writePt, 'report.NAP'), 'a');
                end

                acti_length_in_day = length(app.data) * app.epoch / 3600 / 24;

                if ~isempty(app.nap.data)
                    nap_duration_in_min_per_day = sum(app.nap.data(:, 2) - app.nap.data(:, 1) + 1) * app.epoch / 60 / acti_length_in_day;
                    nap_frequency_per_day       = size(app.nap.data, 1) / acti_length_in_day;

                    % to be refined
                    mid_time = floor((app.nap.data(:, 2) - app.nap.data(:, 1))/2) + app.nap.data(:, 1);
                    mid_time_in_time = app.startTime + (mid_time - 1) * app.epoch ./ 3600 ./ 24;
                    mid_time_in_hour = hour(mid_time_in_time);
                    nap_time_table = table(zeros(length(mid_time), 1), zeros(length(mid_time), 1), zeros(length(mid_time), 1), ...
                        'VariableNames', {'before_noon', 'early_afternoon', 'late_afternoon'});
                    for iN = 1:length(mid_time_in_hour)
                        switch mid_time_in_hour(iN)
                            case {9, 10, 11}
                                nap_time_table.before_noon(iN) = 1;
                            case {12, 13, 14, 15}
                                nap_time_table.early_afternoon(iN) = 1;
                            case {16, 17, 18, 19}
                                nap_time_table.late_afternoon(iN) = 1;
                        end
                    end

                    freq = sum(nap_time_table{:, :}) ./ length(mid_time_in_hour);
                    [maxfreq, maxind] = max(freq);
                    if maxfreq >= 1/3
                        nap_timing = nap_time_table.Properties.VariableNames{maxind};
                    else
                        nap_timing = 'mixed';
                    end
                else
                    nap_duration_in_min_per_day = 0;
                    nap_frequency_per_day       = 0;
                    nap_timing                  = 'never';
                end

                fprintf(fid, '%s,%.1f,%.1f,%s,%d\r', app.currentFileName, nap_duration_in_min_per_day, nap_frequency_per_day, nap_timing, acti_length_in_day);

                fclose(fid);

                app.statusFeed('Nap estimated and saved with summary estimated and saved.');
            else
                app.statusFeed('Nap estimated and saved.');
            end
        end

        function doSleep(app)
            if ~(isempty(app.sleep.data))
                delete(app.sleep.handle); app.sleep.handle = [];
                delete(app.sleep.text); app.sleep.text = [];
                app.sleep.data = [];
            end

            app.data2.SleepInfo = struct('StartTime', app.sleep.parameter.startTime, 'EndTime', app.sleep.parameter.endTime, ...
                'Method', app.sleep.parameter.method, ...
                'ModeParameter', struct('P', app.sleep.parameter.modeParameter.P, 'V', app.sleep.parameter.modeParameter.V, 'C', app.sleep.parameter.modeParameter.C, 'T', app.sleep.parameter.modeParameter.T), ...
                'Option', app.sleep.parameter.option);
            app.data2 = app.data2.sleepDet;

            % update old var
            app.sleep.data = app.data2.Sleep;
            res = app.data2.SleepSummary.Report{:, :};

            % update Face
            app.updateItem;
            app.statusFeed('Sleep detection done!')

            % summary
            if app.config.SummaryReport
                writePt = fullfile(app.filePath, app.mode);
                if ~(exist(writePt, 'dir') == 7)
                    mkdir(writePt);
                end

                % adjust file name based on time window
                repFileName = "sleep_" + replace(app.sleep.parameter.startTime, ':', '') ...
                    + '_' + replace(app.sleep.parameter.endTime, ':', '') + "_summary.txt";

                if ~(exist(fullfile(writePt, repFileName), 'file') == 2)
                    fid = fopen(fullfile(writePt, repFileName), 'w');
                    fprintf(fid, '%s,%s,%s,%s,%s\n', 'filename', ...
                        ['sleep_duration_in_hour_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['sleep_frequency_in_times_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['times_awake_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')], ...
                        ['waso_in_min_between_' replace(app.sleep.parameter.startTime, ':', '') '_' replace(app.sleep.parameter.endTime, ':', '')]);
                else
                    fid = fopen(fullfile(writePt, repFileName), 'a');
                end

                fprintf(fid, '%s,%f,%f,%f,%f\n', app.currentFileName, res);

                fclose(fid);

                app.statusFeed('Sleep estimated and saved with summary estimated and saved.');
            else
                app.statusFeed('Sleep estimated and saved.');
            end
        end

        function resetDisplay(app)
            if isa(app.actigraphyApp, 'actigraphy')
                if isempty(findobj('Tag', 'actigraphyFig'))
                    app.actigraphyApp = actigraphy(app);
                else
                    cla(app.actigraphyApp.actiAxis);
                end
            else
                if ~isempty(findobj('Tag', 'actigraphyFig'))
                    delete(findobj('Tag', 'actigraphyFig'));
                end
                app.actigraphyApp = actigraphy(app);
            end

            try
                close(app.polarPlotApp);
            end

            % reset all handles
            allModes = {'circadian', 'sleep', 'gap'};
            for iM   = 1:numel(allModes)
                app.(allModes{iM}).handle = [];
                app.(allModes{iM}).text   = [];

                app.(allModes{iM}).gap.handle = [];
                app.(allModes{iM}).gap.text   = [];
            end

            app.gImpute = [];
            app.gSig = [];
        end

        function updateGap(app)
            epi    = app.(lower(app.mode)).gap.data;
            Yrange = app.yrange;

            % update plot
            if ~isempty(app.(lower(app.mode)).gap.handle)
                delete(app.(lower(app.mode)).gap.handle);
                app.(lower(app.mode)).gap.handle = [];
            end

            if ~isempty(app.(lower(app.mode)).gap.text)
                delete(app.(lower(app.mode)).gap.text);
                app.(lower(app.mode)).gap.text = [];
            end
            for iG = 1:size(epi, 1)
                app.(lower(app.mode)).gap.handle(iG) = patch('Parent', app.actigraphyApp.actiAxis, 'XData', [epi(iG, 1) epi(iG, 1) epi(iG, 2) epi(iG, 2)], ...
                    'YData', [Yrange(1) Yrange(1)+.05*diff(Yrange) Yrange(1)+.05*diff(Yrange) Yrange(1)], 'FaceColor', [.4 .4 .4], 'EdgeColor', 1-(1-.2*[.4 .4 .4]), 'FaceAlpha', 0.2);
                app.(lower(app.mode)).gap.text(iG) = text(app.actigraphyApp.actiAxis, epi(iG, 1), Yrange(1) + .03*diff(Yrange), ['Gap: # ' num2str(iG)], 'Color', [.4 .4 .4], 'Rotation', 30);
            end


            % switch layers
            child = app.actigraphyApp.actiAxis.Children;
            child = [child(end-length(app.gImpute):end); child(1:end-length(app.gImpute)-1)];
            app.actigraphyApp.actiAxis.Children = child;

            drawnow;
        end

        function reset(app)
            allModes = {'circadian', 'sleep', 'gap'};
            for iM   = 1:numel(allModes)
                app.(allModes{iM}).data = [];
                app.(allModes{iM}).gap.data = [];
            end

            % disable menu item
            app.PolarPlotMenu.Enable = 'off';
            app.ISMenu.Enable = 'off';

            % close extra app windows
            try
                close(app.polarPlotApp);
            end
            try
                close(app.isApp);
            end
            try
                close(app.actogramApp.actigraphyFig);
            end
            close(findall(groot, 'Type', 'figure', '-regexp', 'Name', 'Accelerometer Data Preview'));
        end

        function loadActi(app)
            app.message = "Directory: " + app.filePath;

            loadData(app);

            if app.dataLoaded
                loadExist(app);
            end

            if app.dataLoaded && ~strcmpi(app.mode, 'gap') && app.config.Gap
                loadGap(app);
            end

            app.TextArea.Value = app.message;
            app.message = [];

            function loadData(app)
                % load data
                currentFile = fullfile(app.filePath, app.currentFileName);

                if ~(exist(currentFile, 'file') == 2)
                    app.message    = [app.message; ""; app.currentFileName + ": not exist!"];
                    app.dataLoaded = 0;
                    return;
                end

                % data
                % when RunMode is Raw, perform different loading scheme
                switch app.mode
                    case 'Raw'
                        % do nothing at this moment
                        % do actual when click Plot button
                        app.dataLoaded = 1;
                    otherwise
                        recording   = load(currentFile);
                        app.message = [app.message; ""; app.currentFileName];
                        app.message = [app.message; "  Length: " + numel(recording) + " points."];

                        % epoch
                        switch app.config.EpochParserMode
                            case 'All Same'
                                app.epoch = app.config.EpochParserProperties.SameEpoch;
                                app.message = [app.message; "  Epoch: " + num2str(app.epoch) + " seconds."];
                                app.message = [app.message; "  Duration: " + sprintf('%.2f', numel(recording)*app.epoch/3600) + " hours."];
                            case 'From Filename ezDecoder'
                                nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                                try
                                    dtparts   = nameparts{app.config.EpochParserProperties.EpochInd};
                                    dt        = regexp(dtparts, '\d*', 'match');
                                    app.epoch = eval(dt{1});
                                    app.message = [app.message; "  Epoch: " + num2str(app.epoch) + " seconds."];
                                    app.message = [app.message; "  Duration: " + sprintf('%.2f', numel(recording)*app.epoch/3600) + " hours."];
                                catch
                                    app.epoch   = nan;
                                    app.message = [app.message; "  Epoch: CANNOT parse filename."];
                                    app.message = [app.message; "  Duration: N/A."];
                                end
                            case 'From File'
                            case 'None'
                                app.epoch   = 1;
                                app.message = [app.message; "  Epoch: N/A."];
                                app.message = [app.message; "  Duration: N/A."];
                        end

                        % time
                        switch app.config.TimeParserMode
                            case 'From File'
                                [~, fileID] = fileparts(app.currentFileName);
                                if any(string(app.startTimeFileInfo.FileID) == string(fileID))
                                    app.timeSet   = 1;
                                    app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
                                else
                                    app.timeSet   = 0;
                                end
                            case 'From Filename ezDecoder'
                                nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
                                try
                                    app.startTime = datetime(...
                                        nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
                                        'InputFormat', ...
                                        app.config.TimeParserProperties.DatetimeFormat);
                                    app.timeSet   = 1;

                                    app.message   = [app.message; "  Start Time: " + datestr(app.startTime) + " seconds."];
                                catch
                                    app.timeSet   = 0;
                                    app.message   = [app.message; "  Start Time: CANNOT parse filename."];
                                end
                            case 'All Same'
                                app.startTime = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                                app.timeSet   = 1;
                                app.message   = [app.message; "  Start Time: " + datestr(app.startTime) + " seconds."];
                            case 'None'
                                app.timeSet   = 0;
                                app.startTime = NaT;
                                app.message   = [app.message; "  Start Time: N/A."];
                        end

                        app.message = [app.message; ""];

                        app.data2 = acti(recording, 'StartTime', app.startTime, 'Epoch', app.epoch);
                        app.dataLoaded = 1;

                        % Dec 19, 2022
                        % auto QC
                        app.data2 = app.data2.qc;
                        app.message = [app.message; "Auto QC:"; sprintf("\tpass: %d", app.data2.QCimpression.pass); sprintf("\tmessage: %s", app.data2.QCimpression.message); ""];

                        if ~app.data2.QCimpression.pass
                            app.message = [app.message; "Further analysis is NOT recommended!"];
                        end

                        % to file
                        if app.config.SummaryReport
                            tblInit = table(string(app.currentFileName), app.data2.QCimpression.pass, app.data2.QCimpression.message, ...
                                'VariableNames', {'filename', 'qc_pass', 'qc_message'});

                            writePt = fullfile(app.filePath, "Auto_QC");
                            if ~(exist(writePt, 'dir') == 7)
                                mkdir(writePt);
                            end

                            if ~(exist(fullfile(writePt, 'report.QC'), 'file') == 2)
                                writetable(tblInit, fullfile(writePt, 'report.QC'), ...
                                    'FileType', 'text', 'WriteVariableNames', 1);
                            else
                                % Mar 27, 2023
                                % disable writetable to tolerate older MATLAB
                                % writetable(tblInit, fullfile(writePt, 'report.QC'), ...
                                %     'FileType', 'text', 'WriteVariableNames', 0, 'WriteMode', 'append');
                                fid = fopen(fullfile(writePt, 'report.QC'), 'a');
                                fprintf(fid, '%s,%d,%s\r\n', tblInit.filename, tblInit.qc_pass, tblInit.qc_message);
                                fclose(fid);
                            end

                        end

                        % propagate to ezActi properties
                        app.data        = app.data2.Data;
                        app.dataImputed = app.data;
                        app.x           = app.data2.Point;
                end
            end

            function loadExist(app)
                if app.config.ExistedFlag
                    currentEpi = fullfile(app.filePath, app.mode, [app.currentFileNameNoExt app.importedEpiSuffix]);
                    if ~(exist(currentEpi, 'file') == 2)
                        app.message = [app.message; "./" + app.mode + "/" + [app.currentFileNameNoExt app.importedEpiSuffix] + " NOT FOUND."];
                        app.(lower(app.mode)).data = [];
                    else
                        app.(lower(app.mode)).data = load(currentEpi);
                        app.message = [app.message; "./" + app.mode + "/" + [app.currentFileNameNoExt app.importedEpiSuffix] + " loaded."];
                        app.(lower(app.mode)).matchFile = app.currentFileName;
                    end

                    % ezActi2 assignments
                    switch app.mode
                        case {'Gap', 'Sleep'}
                            app.data2.(app.mode) = app.(lower(app.mode)).data;
                    end
                end
            end

            function loadGap(app)
                currentEpi = fullfile(app.filePath, 'Gap', [app.currentFileNameNoExt app.importedGapSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.message  = [app.message; "./Gap/" + [app.currentFileNameNoExt app.importedGapSuffix] + " NOT FOUND."];
                    app.(lower(app.mode)).gap.data = [];
                else
                    app.(lower(app.mode)).gap.data = load(currentEpi);
                    app.message  = [app.message; "./Gap/" + [app.currentFileNameNoExt app.importedGapSuffix] + " loaded."];
                end

                % ezActi2 assignments
                app.data2.Gap = app.(lower(app.mode)).gap.data;
            end
        end

        function closeChildren(app)
            if isa(app.actigraphyApp, 'actigraphy')
                if isvalid(app.actigraphyApp)
                    delete(app.actigraphyApp);
                end
            else
                if ~isempty(findobj('Tag', 'actigraphyFig'))
                    delete(findobj('Tag', 'actigraphyFig'));
                end
            end

            if ~isempty(findobj('Tag', 'DFAConf'))
                delete(findobj('Tag', 'DFAConf'));
            end
            if ~isempty(findobj('Tag', 'dfaPlotFig'))
                delete(findobj('Tag', 'dfaPlotFig'));
            end
            try
                close(app.polarPlotApp);
            end
            close(findall(groot, 'Type', 'figure', '-regexp', 'Name', 'Accelerometer'));
            close(findall(groot, 'Type', 'figure', '-regexp', 'Name', 'Raw Data Visualization'));
        end
        
        function doRaw(app)
            if ~app.dataLoaded
                return;
            end

            close(findall(groot, 'Type', 'figure', '-regexp', 'Name', 'Raw Data Visualization'));

            currentFile = fullfile(app.filePath, app.currentFileName);
            app.statusFeed('Digesting loading parameters ...', 'warn')
            drawnow;

            opts = detectImportOptions(currentFile);
            opts.SelectedVariableNames = opts.VariableNames([app.raw.Time, app.raw.X, app.raw.Y, app.raw.Z]);

            app.statusFeed('Loading data based on selected time and 3-D acceleration columns. This takes time. Be patient ...', 'warn');
            drawnow;

            warning('off', 'MATLAB:table:ModifiedAndSavedVarnames');
            dat = readtimetable(currentFile, opts, ...
                'RowTimes', opts.VariableNames{app.raw.Time});
            warning('on', 'MATLAB:table:ModifiedAndSavedVarnames');

            % trim
            timeAxis = dat.(opts.VariableNames{app.raw.Time});
            [firstY, firstM, firstD] = ymd(timeAxis(1));
            [lastY,  lastM,  lastD]  = ymd(timeAxis(end));

            firstDay = datetime(string(datetime(firstY, firstM, firstD, 'Format', 'yyyyMMdd')) + ...
                " " + app.raw.StartTime, 'InputFormat', 'yyyyMMdd HH:mm');
            lastDay  = datetime(string(datetime(lastY,  lastM,  lastD, 'Format', 'yyyyMMdd')) + ...
                " " + app.raw.EndTime, 'InputFormat', 'yyyyMMdd HH:mm');

            if timeAxis(1) > firstDay
                firstDay = firstDay + days(1);
            end
            if timeAxis(end) < lastDay
                lastDay  = lastDay - days(1);
            end

            app.raw.firstDay = firstDay;

            dat(timeAxis < firstDay, :)   = [];
            timeAxis(timeAxis < firstDay) = [];

            dat(timeAxis > lastDay,  :)  = [];
            timeAxis(timeAxis > lastDay) = [];

            switch app.raw.Unit
                case 'milli-g'
                    dat{:, :} = dat{:, :} ./ 1000;
            end

            % get stable episodes
            app.statusFeed('Performing calibration ...', 'warn')
            drawnow;
            [consDat, errorMsg, success] = getNoMovingEpi(dat, app.raw.Fs, ...
                app.config.RawSetting.NonMovingThreshold, ...
                app.config.RawSetting.NonMovingDuration);

            if ~success
                app.statusFeed(errorMsg, 'r');
                return;
            end

            % recalibration
            [para, verbose, success] = optiCalib(consDat{:, :}, 'plot', double(app.config.RawSetting.PlotCalibration));
            if ~success
                app.statusFeed('Failed to calibrate acceleration signals. Abort.', 'r');
                return;
            end

            rAcc = calibAcc(dat{:, :}, para);
            rDat = table2timetable(array2table(rAcc, 'VariableNames', dat.Properties.VariableNames), ...
                "RowTimes", timeAxis);

            % convertion
            app.statusFeed('Converting to activity counts ...', 'warn');
            drawnow;

            chgAcc = abs(diff(rDat{:, :}, 1, 1));
            chgAccFiltered = chgAcc;
            chgAccFiltered = floor(chgAccFiltered / app.config.RawSetting.BackgroundNoise) * app.config.RawSetting.BackgroundNoise;

            magAcc = sqrt(sum(chgAccFiltered.^2, 2)) * app.raw.Fs;

            magAccTT = array2timetable(magAcc, 'RowTimes', rDat.Time(2:end));

            % calculate activity counts per epoch
            epochAcc = retime(magAccTT, "regular", "sum", "SampleRate", 1/app.raw.Epoch);
            app.raw.data = epochAcc.(1);

            app.statusFeed('Convertion finished.');
            drawnow;

            % show results
            if app.config.RawSetting.PlotAccCounts
                % 1. raw 3-D acceleration
                hf1 = figure(Color='w', Units='centimeters', Position=[5, 5, 25, 15], Name='Raw Data Visualization: Accelerometer vs. Activity Counts');
                ha1 = axes(hf1, Units='normalized', Position=[.09, .58, .82, .4]);
                plot(ha1, dat.StartTime_Local_, dat{:, :});
                hl1 = legend({'X', 'Y', 'Z'}, Location='best');
                hl1.Box = 'off';

                ylabel('Acceleration, g');
                xlabel('Date and time');


                % 2. activity count
                ha2 = axes(hf1, Units='normalized', Position=[.09, .08, .82, .4]);
                stem(ha2, epochAcc.Time, epochAcc.(1)./1e4, 'Marker', 'none', 'Color', 'b');

                ylabel('Activity count (\times 10^4), a.u.');
                xlabel('Date and time');

                linkaxes([ha1, ha2], 'x');
            end
        end
        
        function resetRawControl(app, status)
            app.TimeColumnDropDown.Enable      = status;
            app.XColumnDropDown.Enable         = status;
            app.YColumnDropDown.Enable         = status;
            app.ZColumnDropDown.Enable         = status;
            app.StartEditField_2.Enable        = status;
            app.EndEditField_2.Enable          = status;
            app.UnitDropDown.Enable            = status;
            app.EpochEditField.Enable          = status;
            app.AdvancedButton.Enable          = status;
            app.FsEditField.Enable             = status;
        end
    end

    methods (Access = public)
        % function loadData(app)
        %     if ~app.dataDefined
        %         return;
        %     end
        % 
        %     if app.startIndex > length(app.fileList)
        %         app.statusFeed('Already the last file!', 'warn');
        %         app.startIndex = app.startIndex + 1; % this makes sure the auto loop can end succesfully
        %         return;
        %     end
        % 
        %     app.ListBox.Value   = app.startIndex;
        %     app.currentFileName = app.fileList{app.startIndex};
        % 
        %     % scroll list box
        %     if app.startIndex > 10
        %         scroll(app.ListBox, app.startIndex);
        %     end
        % 
        %     % load data
        %     currentFile = fullfile(app.filePath, app.currentFileName);
        % 
        %     if ~(exist(currentFile, 'file') == 2)
        %         app.statusFeed(['No such a file: ' app.currentFileName], 'r');
        %         return;
        %     end
        % 
        %     recording   = load(currentFile);
        %     recording   = recording(:, 1);
        %     % this eventually won't be a good solution as -10 might be
        %     % meaningful for other data, NEED TO ALWAYS REMEMBER TO CHECK
        %     % NAN IN OTHER FUNCTIONS
        %     % recording(isnan(recording)) = -10; % to better visually identify single NaN
        %     app.data        = recording;
        %     app.dataImputed = recording;       % shadowing data
        %     app.x           = 1:size(app.data, 1);
        % 
        %     % ezActi2
        %     % time
        %     switch app.config.TimeParserMode
        %         case 'From File'
        %             [~, fileID] = fileparts(app.currentFileName);
        %             if any(string(app.startTimeFileInfo.FileID) == string(fileID))
        %                 app.timeSet   = 1;
        %                 app.startTime = app.startTimeFileInfo.StartTime(string(app.startTimeFileInfo.FileID) == string(fileID));
        %             else
        %                 app.timeSet   = 0;
        %             end
        %         case 'From Filename ezDecoder'
        %             nameparts = strsplit(app.currentFileName, app.config.FileNameSeparator);
        %             app.startTime = datetime(...
        %                 nameparts{app.config.TimeParserProperties.DatetimeInd}, ...
        %                 'InputFormat', ...
        %                 app.config.TimeParserProperties.DatetimeFormat);
        %             app.timeSet   = 1;
        %         case 'All Same'
        %             app.startTime = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
        %             app.timeSet   = 1;
        %         case 'None'
        %             app.timeSet   = 0;
        %             app.startTime = NaT;
        %     end
        % 
        %     % epoch
        %     switch app.config.EpochParserMode
        %         case 'All Same'
        %             app.epoch = app.config.EpochParserProperties.SameEpoch;
        %     end
        % 
        %     app.data2 = acti(recording, 'StartTime', app.startTime, 'Epoch', app.epoch);
        % end
        % 
        % function loadGap(app)
        %     currentEpi = fullfile(app.filePath, [upper(app.importedGapSuffix(2)) app.importedGapSuffix(3:end)], [app.currentFileName(1:end-4) app.importedGapSuffix]);
        %     if ~(exist(currentEpi, 'file') == 2)
        %         app.statusFeed(['Showing ' app.currentFileName '. No file: ' app.currentFileName(1:end-4) app.importedGapSuffix ' from import found.'], 'warn');
        %         app.gap.data = [];
        %     else
        %         app.gap.data = load(currentEpi);
        %         app.statusFeed(['Showing ' app.currentFileName ' with imported file: ' app.currentFileName(1:end-4) app.importedGapSuffix]);
        %         app.gap.matchFile = app.currentFileName;
        %     end
        % end
        % 
        % % hack-in private for use outside this
        % function statusFeed_(app, varargin)
        %     app.statusFeed(varargin{:});
        % end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.SelectedTab = app.GapTab;
            app.mode = app.GapTab.Title;
            app.statusFeed("Ready");

            % 20221115 - temporal solution
            % bring the focus to any tab that is not the default
            % and make the right default in top layer tab selection chg
            % otherwise the default will not be selected even forced to
            app.TabGroup2.SelectedTab = app.NPTab;
            app.AccelerometertoActivityCountsPanel.Visible = 'off';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.startIndex      = app.ListBox.Value;
            app.currentFileName = app.fileList{app.startIndex};

            temp = strsplit(app.currentFileName, '.');
            app.currentFileNameNoExt = temp{1};

            % ezActi2
            % load data here, and update file info
            loadActi(app);

            % update current tab view
            app.resetControl(event, app.mode);

            % do plot when click Plot button
            app.PlotButton.Text = 'Plot';
            app.statusFeed('Click Plot button to plot the signal');
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % reset
            app.reset;

            switch app.pointer
                case 'next'
                    % load next data
                    if app.startIndex > length(app.fileList)
                        app.statusFeed('No more files', 'warn');
                        return;
                    end
                    app.ListBox.Value   = app.startIndex;
                    app.currentFileName = app.fileList{app.startIndex};

                    temp = strsplit(app.currentFileName, '.');
                    app.currentFileNameNoExt = temp{1};

                    loadActi(app);

                    % scroll list box if needed
                    if app.startIndex > 16 % hard coded here
                        scroll(app.ListBox, app.startIndex);
                    end
            end

            % if raw
            switch app.mode
                case 'Raw'
                    if ~app.raw.Apply2All
                        currentFile = fullfile(app.filePath, app.currentFileName);
                        preViewApp  = AccPreview(currentFile);
                        app.TimeColumnDropDown.Items     = preViewApp.colNames;
                        app.TimeColumnDropDown.ItemsData = 1:numel(preViewApp.colNames);

                        app.XColumnDropDown.Items     = preViewApp.colNames;
                        app.XColumnDropDown.ItemsData = 1:numel(preViewApp.colNames);

                        app.YColumnDropDown.Items     = preViewApp.colNames;
                        app.YColumnDropDown.ItemsData = 1:numel(preViewApp.colNames);

                        app.ZColumnDropDown.Items     = preViewApp.colNames;
                        app.ZColumnDropDown.ItemsData = 1:numel(preViewApp.colNames);
                    end

                    app.dataShown = 1;

                    app.PlotButton.Text = 'Next';
                    app.pointer = 'next';
                    app.startIndex = app.startIndex + 1;
                    return;
            end

            % otherwise
            % show data: reset display
            app.resetDisplay;

            % show data: reset control
            app.resetControl(event, app.mode);

            % show data: show
            dataShow(app);

            if app.importedEpi
                currentEpi = fullfile(app.filePath, [upper(app.importedEpiSuffix(2)) app.importedEpiSuffix(3:end)], [app.currentFileName(1:end-4) app.importedEpiSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.statusFeed(['Showing ' app.currentFileName '. No file: ' app.currentFileName(1:end-4) app.importedEpiSuffix ' from import found.'], 'warn');
                    app.(app.importedEpiSuffix(2:end)).data = [];
                else
                    app.(app.importedEpiSuffix(2:end)).data = load(currentEpi);

                    % update
                    app.updateItem;
                    app.statusFeed(['Showing ' app.currentFileName ' with imported file: ' app.currentFileName(1:end-4) app.importedEpiSuffix]);

                    app.(lower(app.mode)).matchFile = app.currentFileName;
                end
            else
                app.statusFeed(['Showing ' app.currentFileName]);
            end

            if app.importedGap
                currentEpi = fullfile(app.filePath, [upper(app.importedGapSuffix(2)) app.importedGapSuffix(3:end)], [app.currentFileName(1:end-4) app.importedGapSuffix]);
                if ~(exist(currentEpi, 'file') == 2)
                    app.statusFeed(['. No file: ' app.currentFileName(1:end-4) app.importedGapSuffix ' from import found.'], 'warn', app.Status.Text);

                    % propergate
                    app.circadian.gap.data = [];
                    app.sleep.gap.data     = [];
                    app.nap.gap.data       = [];
                    app.gap.gap.data       = [];
                else
                    gapData = load(currentEpi);

                    % propergate
                    app.circadian.gap.data = gapData;
                    app.sleep.gap.data     = gapData;
                    app.nap.gap.data       = gapData;
                    app.gap.gap.data       = gapData;

                    % update
                    app.updateGap;
                    app.statusFeed([' with imported file: ' app.currentFileName(1:end-4) app.importedGapSuffix]);
                end
            end

            % show actogram
            if app.config.Actogram
                try
                    close(app.actogramApp);
                end

                h = app.data2.plotActogram;
                app.actogramApp = h;
                h.actigraphyFig.Name = h.actigraphyFig.Name + ": " + app.currentFileName;

                % show toolbar
                axtoolbar(app.actogramApp.actiAxis, {'export', 'zoomin', 'zoomout', 'restoreview', 'pan', 'datacursor'});
                app.actogramApp.actiAxis.Toolbar.Visible    = 'on';
            end

            app.dataShown = 1;

            app.PlotButton.Text = 'Next';
            app.pointer = 'next';
            app.startIndex = app.startIndex + 1;
        end

        % Close request function: ActigraphycontrolpanelUIFigure
        function ActigraphycontrolpanelUIFigureCloseRequest(app, event)
            app.closeChildren;

            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.GapTab
                    app.previewUITable.Visible = 'on';
                    app.InterpButton.Enable    = 'on';
                    app.CropButton.Enable      = 'on';
                    app.FlipButton.Enable      = 'on';
                    app.ClearButton.Enable     = 'on';

                    app.circShowArea.Visible   = 'off';

                    app.previewUITable.ColumnName = {'Start', 'End'};
                case app.SleepTab
                    app.previewUITable.Visible = 'on';

                    app.InterpButton.Enable    = 'off';
                    app.CropButton.Enable      = 'on';
                    app.FlipButton.Enable      = 'on';
                    app.ClearButton.Enable     = 'on';

                    app.circShowArea.Visible   = 'off';
                case app.CircadianTab
                    % set default method
                    app.TabGroup2.SelectedTab = app.CosinorTab;

                    app.previewUITable.Visible = 'off';
                    app.InterpButton.Enable    = 'off';
                    app.CropButton.Enable      = 'off';
                    app.FlipButton.Enable      = 'off';
                    app.ClearButton.Enable     = 'on';

                    app.circShowArea.Visible   = 'on';
            end
            app.mode = selectedTab.Title;

            if ~strcmp(app.gap.matchFile, app.currentFileName)
                app.resetControl([], app.mode);
            end
            app.statusFeed(['Run mode defined as: ' app.mode]);
        end

        % Value changed function: ManAutoSwitch
        function ManAutoSwitchValueChanged(app, event)
            value = app.ManAutoSwitch.Value;
            app.gap.parameter.method = value;
            switch value
                case 'Manual'
                    enable(app, [app.StartEditField, ...
                        app.StartEditFieldLabel, ...
                        app.EndEditField, ...
                        app.EndEditFieldLabel]);

                    disable(app, [app.ThreEditField, ...
                        app.ThreEditFieldLabel, ...
                        app.MinDurEditField, ...
                        app.MinDurEditFieldLabel, ...
                        app.minLabel, ...
                        app.threUnitDropDown, ...
                        app.MergeifEditField, ...
                        app.MergeifEditFieldLabel, ...
                        app.minLabel_2]);
                case 'Auto'
                    disable(app, [app.StartEditField, ...
                        app.StartEditFieldLabel, ...
                        app.EndEditField, ...
                        app.EndEditFieldLabel]);

                    enable(app, [app.ThreEditField, ...
                        app.ThreEditFieldLabel, ...
                        app.MinDurEditField, ...
                        app.MinDurEditFieldLabel, ...
                        app.minLabel, ...
                        app.threUnitDropDown, ...
                        app.MergeifEditField, ...
                        app.MergeifEditFieldLabel, ...
                        app.minLabel_2]);

                    app.statusFeed('Caution: automated detection will overwrite previously detected gaps.', 'warn');
            end
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.gap.parameter.startIndex = str2double(app.StartEditField.Value);
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.gap.parameter.endIndex = str2double(app.EndEditField.Value);
        end

        % Button pushed function: DoButton
        function DoButtonPushed(app, event)
            if ~app.dataShown
                return;
            end

            app.(['do' app.mode]);
            app.(lower(app.mode)).matchFile = app.currentFileName;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~app.dataShown
                return;
            end

            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: nothing to clear.', [1 .325 .286]);
                return;
            end

            delete(app.(lower(app.mode)).handle); app.(lower(app.mode)).handle = [];

            if ~strcmp(app.mode, 'Circadian')
                delete(app.(lower(app.mode)).text); app.(lower(app.mode)).text = [];
                app.previewUITable.Data = [];
            else
                try delete(app.(lower(app.mode)).text); end
                app.(lower(app.mode)).text = [];
                app.circShowArea.Value = '';
            end

            app.(lower(app.mode)).data = [];

            app.statusFeed(['Detected ' app.mode '(s) cleared.']);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            if ~app.dataShown
                return;
            end

            % raw data saving
            if strcmpi(app.mode, 'raw')
                if isempty(app.raw.data)
                    app.statusFeed('Nothing to write.', 'warn');
                else
                    writePt = fullfile(app.filePath, 'Act_Counts');
                    if ~(exist(writePt, 'dir') == 7)
                        mkdir(writePt);
                    end
                    [~, nm] = fileparts(app.currentFileName);
                    actiFileName = nm + "_" + string(app.raw.firstDay, 'yyyyMMddHHmmss') + '_' + num2str(app.raw.Epoch) + '.txt';
                    writematrix(app.raw.data, fullfile(writePt, actiFileName));
                    app.statusFeed('Activity counts results saved');
                    drawnow;
                end
                return;
            end

            if isempty(app.(lower(app.mode)).data)
                if exist(fullfile(app.filePath, app.mode, [app.currentFileName(1:end-4) '.' lower(app.mode)]), 'file') == 2
                    delete(fullfile(app.filePath, app.mode, [app.currentFileName(1:end-4) '.' lower(app.mode)]));
                    app.statusFeed('Overwriting existing file: deleted (no detectables)!', 'warn');
                else
                    app.statusFeed('Nothing to write.', 'warn');
                end
                return;
            end

            writePt = fullfile(app.filePath, app.mode);
            if ~ (exist(writePt, 'dir') == 7)
                mkdir(writePt);
            end

            switch app.mode
                case 'Circadian'
                    f = '%f\n';
                    switch app.circadian.parameter.method
                        case 'Cosine curve fit'
                            ext = 'cosine';
                        case 'EMD'
                            ext = 'emd';
                    end
                case {'Gap', 'Nap', 'Sleep'}
                    f = '%d,%d\n';
                    ext = lower(app.mode);
            end
            fid = fopen(fullfile(writePt, [app.currentFileName(1:end-4) '.' ext]), 'w');
            fprintf(fid, f, app.(lower(app.mode)).data');
            fclose(fid);

            if app.config.SummaryReport
                app.statusFeed(['Detected ' app.mode ' saved with summary report.']);
            else
                app.statusFeed(['Detected ' app.mode ' saved.']);
            end
        end

        % Cell edit callback: previewUITable
        function previewUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if ~isempty(newData)
                if isnan(newData) % delete a gap
                    app.(lower(app.mode)).data(indices(1), :) = [];
                    delete(app.(lower(app.mode)).handle(indices(1)));
                    delete(app.(lower(app.mode)).text(indices(1)));
                    app.(lower(app.mode)).handle(indices(1)) = [];
                    app.(lower(app.mode)).text(indices(1)) = [];

                    % update table
                    set(app.previewUITable, 'Data', app.(lower(app.mode)).data);

                    % update text display
                    for iT = 1:length(app.(lower(app.mode)).text)
                        set(app.(lower(app.mode)).text(iT), 'String', [app.mode ': # ' num2str(iT)], 'Color', 'm');
                    end
                else
                    app.(lower(app.mode)).data(indices(1), indices(2)) = newData;

                    % update plot
                    set(app.(lower(app.mode)).handle(indices(1)), 'XData', [app.(lower(app.mode)).data(indices(1), 1) app.(lower(app.mode)).data(indices(1), 1) app.(lower(app.mode)).data(indices(1), 2) app.(lower(app.mode)).data(indices(1), 2)]);
                    OrigPos    = get(app.(lower(app.mode)).text(indices(1)), 'Position');
                    OrigPos(1) = app.(lower(app.mode)).data(indices(1), 1);
                    set(app.(lower(app.mode)).text(indices(1)), 'Position', OrigPos);
                end
            end

            % propogate to data2.Gap
            app.data2.Gap = app.(lower(app.mode)).data;
        end

        % Value changed function: ThreEditField
        function ThreEditFieldValueChanged(app, event)
            app.gap.parameter.threshold = eval(app.ThreEditField.Value);
        end

        % Value changed function: MinDurEditField
        function MinDurEditFieldValueChanged(app, event)
            app.gap.parameter.minDuration = str2double(app.MinDurEditField.Value);
        end

        % Value changed function: threUnitDropDown
        function threUnitDropDownValueChanged(app, event)
            app.gap.parameter.thresholdUnit = app.threUnitDropDown.Value;
            switch app.gap.parameter.thresholdUnit
                case 'Range'
                    app.ThreEditField.Value = '[a,b]';
                otherwise
                    app.ThreEditField.Value = '';
            end
        end

        % Value changed function: MergeifEditField
        function MergeifEditFieldValueChanged(app, event)
            app.gap.parameter.minDistance = str2double(app.MergeifEditField.Value);
        end

        % Cell selection callback: previewUITable
        function previewUITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(app.(lower(app.mode)).data)
                set(app.(lower(app.mode)).handle, 'FaceColor', 'm', 'EdgeColor', 'm');
                set(app.(lower(app.mode)).text, 'Color', 'm');

                if ~isempty(indices) % in case conflict from editing table (delete a gap)
                    set(app.(lower(app.mode)).handle(indices(:, 1)), 'FaceColor', 'g', 'EdgeColor', 'g');
                    set(app.(lower(app.mode)).text(indices(:, 1)), 'Color', 'g');
                end
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no episode info to help crop data.', 'warn');
                return;
            end

            writePt = fullfile(app.filePath, 'Crop');
            if ~(exist(writePt, 'dir') == 7)
                mkdir(writePt);
            end

            for iE  = 1:size(app.(lower(app.mode)).data, 1)
                fid = fopen(fullfile(writePt, [app.currentFileName(1:end-4) '_start_index_' num2str(app.(lower(app.mode)).data(iE, 1)) '.crop']), 'w');
                fprintf(fid, '%f\r', app.dataImputed(app.(lower(app.mode)).data(iE, 1):app.(lower(app.mode)).data(iE, 2)));
                fclose(fid);
            end

            app.statusFeed(['In total ' num2str(size(app.(lower(app.mode)).data, 1)) ' cropped data saved!']);
        end

        % Button pushed function: InterpButton
        function InterpButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no missing data to impute.', 'warn');
                return;
            end
            called = fitHelper(app);
            app.statusFeed('Waiting for configuration ...');
            uiwait(called.wizardFigure)
            if called.go
                app.gapImputationMethod = called.fitMethod;
                app.gapImputationWindow = called.fitWindowLength;
                app.gapImputationCrit   = called.fitIfLessThan;
                app.gapImputationSave   = called.fitSave;

                called.delete;

                app.gapImputation;
            else
                app.statusFeed('Data imputation was not corrected configured.');

                called.delete;
                return;
            end
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            if isempty(app.(lower(app.mode)).data)
                app.statusFeed('Caution: no episodes to flip', 'warn');
                return;
            end
            app.(lower(app.mode)).data = transSegGap(app.(lower(app.mode)).data, length(app.data));
            app.updateItem;
            app.statusFeed('Caution: episodes flipped but not saved!', 'warn');
        end

        % Value changed function: TargetcomponentEditField
        function TargetcomponentEditFieldValueChanged(app, event)
            value = app.TargetcomponentEditField.Value;
            app.circadian.parameter.target = value;
        end

        % Value changed function: HarmonicsEditField
        function HarmonicsEditFieldValueChanged(app, event)
            value = app.HarmonicsEditField.Value;
            if isempty(value)
                app.circadian.parameter.harmonic = [];
                app.CIalphaEditField.Enable      = 1;
            else
                app.circadian.parameter.harmonic = eval(value);
                app.CIalphaEditField.Enable      = 0;
            end
        end

        % Value changed function: MinlengthEditField
        function MinlengthEditFieldValueChanged(app, event)
            value = app.MinlengthEditField.Value;
            app.circadian.parameter.minLength = eval(value);
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            % put other callbacks on queue
            % app.AutoButton.Interruptible = 'off';

            % for raw data processing
            % disable visualization to prevent generating figures
            if strcmpi(app.mode, 'raw')
                app.config.RawSetting.PlotCalibration = 0;
                app.config.RawSetting.PlotAccCounts   = 0;
            end

            while app.startIndex <= length(app.fileList)
                app.PlotButtonPushed(event);
                app.DoButtonPushed(event);
                app.WriteButtonPushed(event);

                drawnow;
            end

            app.statusFeed('Finished!')
        end

        % Menu selected function: AboutezActiMenu
        function AboutezActiMenuSelected(app, event)
            % close in case, before call
            try
                close(findall(0, 'Type', 'figure', 'Name', 'About ezActi'));
            end
            
            aboutEz("About ezActi", "ezacti2.png", "Ver.: 3.0.1017");
        end

        % Value changed function: ModeDropDown_Sleep
        function ModeDropDown_SleepValueChanged(app, event)
            value = app.ModeDropDown_Sleep.Value;
            app.sleep.parameter.method = value;

            switch value
                case 'Cole-Kripke'
                    app.sleep.parameter.modeParameter.P = 0.001;
                    app.sleep.parameter.modeParameter.V = [106 54 58 76 230 74 67];
                    app.sleep.parameter.modeParameter.C = 0;

                    app.WakethreDropDown.Visible = "off";
                    app.WakethreDropDownLabel.Visible = "off";
                case 'Actiware'
                    app.sleep.parameter.modeParameter.T = 40;
                    app.WakethreDropDown.Visible = "on";
                    app.WakethreDropDownLabel.Visible = "on";
            end

            % to cross ref nap mode
            app.ModeDropDown.Value = value;
            app.nap.parameter.modeParameter = app.sleep.parameter.modeParameter;
        end

        % Value changed function: SleepStartEditField
        function SleepStartEditFieldValueChanged(app, event)
            value = app.SleepStartEditField.Value;
            app.sleep.parameter.startTime = value;
        end

        % Value changed function: SleepEndEditField
        function SleepEndEditFieldValueChanged(app, event)
            value = app.SleepEndEditField.Value;
            app.sleep.parameter.endTime = value;
        end

        % Menu selected function: DetrendedfluctuationanalysisDFAMenu
        function DetrendedfluctuationanalysisDFAMenuSelected(app, event)
            dfaConfig(app);
            app.statusFeed('Controlling DFA on DFA Configuration. Wait to finish ...', 'warn');
        end

        % Menu selected function: ImportWizardMenu
        function ImportWizardMenuSelected(app, event)
            called = ImportWizard3(event.Source.Text);
            app.statusFeed('Waiting for importing data ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.Status.Text   = [num2str(numel(called.selectedFiles)) ' files imported.'];
                app.filePath      = called.dataFolder;
                app.fileList      = called.selectedFiles;

                app.ListBox.Items     = app.fileList;
                app.ListBox.ItemsData = 1:length(app.fileList);
                app.startIndex        = 1;
                app.ListBox.Value     = 1;

                app.config = called.configStruct;

                % adapt to ezActi previous version
                switch app.config.TimeParserMode
                    case 'none'
                        app.timeDispMode = 0;
                    case 'All Same'
                        app.timeDispMode = 3;
                        app.startTime    = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                        app.timeSet      = 1;
                    case 'From Filename ezDecoder'
                        app.timeDispMode = 2;
                    case 'From File'
                        app.timeDispMode = 1;
                end

                switch app.config.EpochParserMode
                    case 'All Same'
                        app.epoch = app.config.EpochParserProperties.SameEpoch;
                end

                app.importedEpi       = called.configStruct.ExistedFlag;
                app.importedEpiSuffix = convertStringsToChars(called.configStruct.ExistedExtension);

                app.importedGap       = called.configStruct.Gap;
                app.importedGapSuffix = convertStringsToChars(called.configStruct.GapExtension);

                app.mode        = app.config.RunMode;
                app.dataDefined = 1;
            else
                app.statusFeed('No data imported.');
                % app.dataDefined = 0;
            end

            if app.dataDefined
                % clear prior dependent data
                app.reset;
                app.resetDisplay;

                % load first signal
                app.ListBoxValueChanged(event);

                switch app.mode
                    case {'Gap', 'Circadian', 'Sleep'}
                        app.AccelerometertoActivityCountsPanel.Visible = 'off';

                        app.TabGroup.SelectedTab = app.(app.mode + "Tab");
                        app.TabGroupSelectionChanged();
                    case 'Raw'
                        app.AccelerometertoActivityCountsPanel.Visible = 'on';

                        app.raw.Apply2All = 0;
                        app.ApplytoAllFilesCheckBox.Value  = 0;

                        app.resetRawControl('on');

                        app.InterpButton.Enable    = 'off';
                        app.CropButton.Enable      = 'off';
                        app.FlipButton.Enable      = 'off';
                        app.ClearButton.Enable     = 'off';
                end

                app.closeChildren;
            end

            called.delete;       
        end

        % Menu selected function: OptionMenu
        function OptionMenuSelected(app, event)
            called = ImportWizard3(event.Source.Text, app.config, app.fileList);

            app.statusFeed('Configuring the App ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.Status.Text  = 'Configuration done.';

                app.config = called.configStruct;

                % adapt to ezActi previous version
                switch app.config.TimeParserMode
                    case 'none'
                        app.timeDispMode = 0;
                    case 'All Same'
                        app.timeDispMode = 3;
                        app.startTime    = datetime(app.config.TimeParserProperties.SameDateTime, 'InputFormat', 'HH:mm:ss');
                        app.timeSet      = 1;
                    case 'From Filename ezDecoder'
                        app.timeDispMode = 2;
                    case 'From File'
                        app.timeDispMode = 1;
                end

                switch app.config.EpochParserMode
                    case 'All Same'
                        app.epoch = app.config.EpochParserProperties.SameEpoch;
                end

                %                 app.importedEpi       = called.configStruct.ExistedFlag;
                %                 app.importedEpiSuffix = called.configStruct.ExistedExtension;
                %
                %                 app.importedGap       = called.configStruct.Gap;
                %                 app.importedGapSuffix = called.configStruct.GapExtension;

                % app.mode        = app.config.RunMode;
            else
                app.statusFeed('Nothing changed!');
            end

            if called.imported
                app.TabGroup.SelectedTab = app.(app.mode + "Tab");
            end

            called.delete;
        end

        % Value changed function: CIalphaEditField
        function CIalphaEditFieldValueChanged(app, event)
            value = app.CIalphaEditField.Value;
            app.circadian.parameter.method.ci = value;
        end

        % Menu selected function: ActogramMenu
        function ActogramMenuSelected(app, event)
            h = app.data2.plotActogram;
            app.actogramApp = h;
            h.actigraphyFig.Name = h.actigraphyFig.Name + ": " + app.currentFileName;
        end

        % Menu selected function: PolarPlotMenu
        function PolarPlotMenuSelected(app, event)
            ss = get(0, 'ScreenSize');
            sa = ss(4);
            if ss(3) < sa
                sa = ss(3);
            end
            sa = round(sa / 2);

            app.polarPlotApp = uifigure('Units', 'pixels', 'Position', [ss(3)-1.3*sa ss(4)-1.3*sa sa sa], 'Color', 'w', 'Tag', 'PolarFig');
            ax = axes(app.polarPlotApp);
            c = app.circadian.parameter.UserData;
            c.plot(ax);
            app.polarPlotApp.Name = "Polar: " + app.currentFileName;
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            switch selectedTab.Title
                case 'Cosinor'
                    app.statusFeed('To perform cosine curve fit.');

                    app.circadian.parameter.method = 'Cosine curve fit';
                    app.previewUITable.Visible     = 'off';
                    app.circShowArea.Visible       = 'on';

                    % refresh shared parameters
                    app.circadian.parameter.target = app.TargetcomponentEditField.Value;
                case 'NP'
                    app.statusFeed('To perform nonparametric analysis.');

                    app.circadian.parameter.method = 'Nonparametric';
                    app.previewUITable.Visible     = 'on';
                    app.circShowArea.Visible       = 'off';
                    app.PolarPlotMenu.Enable       = 'off';

                    % refresh shared parameters
                    app.circadian.parameter.target = eval(app.PeriodEditField.Value);
                case 'EMD'
                    app.statusFeed('To perform empirical mode decomposition.');

                    app.circadian.parameter.method = 'EMD';
                    app.PolarPlotMenu.Enable       = 'off';
            end
        end

        % Value changed function: FixLenghEditField
        function FixLenghEditFieldValueChanged(app, event)
            value = app.FixLenghEditField.Value;
            app.circadian.parameter.fixlength = value;
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = app.ScaleEditField.Value;
            try
                app.circadian.parameter.scale = eval(value);
            catch
                app.statusFeed("Invalid expression used for time scale!", "error");
            end
        end

        % Value changed function: PeriodEditField
        function PeriodEditFieldValueChanged(app, event)
            value = app.PeriodEditField.Value;
            try
                app.circadian.parameter.target = eval(value);
            catch
                app.statusFeed("Invalid expression used for period!", "error");
            end
        end

        % Menu selected function: ISMenu
        function ISMenuSelected(app, event)
            ss = get(0, 'ScreenSize');
            sa = ss(4);
            if ss(3) < sa
                sa = ss(3);
            end
            sa = round(sa / 2);

            app.isApp = uifigure('Units', 'pixels', 'Position', [1 ss(4)-1.3*sa sa sa], 'Color', 'w', 'Tag', 'ISFig');
            ax = axes(app.isApp);

            p = plot(ax, app.circadianTemp.period, app.circadianTemp.is, 'MarkerSize', 5);
            xlabel(ax, 'period in hour');
            ylabel(ax, 'interdaily stability');
            legend(ax, p, "scale: "+string(num2cell(app.circadian.parameter.scale)), ...
                'location', 'best');
            app.isApp.Name = "IS: " + app.currentFileName;
        end

        % Value changed function: emdMinLen
        function emdMinLenValueChanged(app, event)
            value = app.emdMinLen.Value;
            app.circadian.parameter.minLength = eval(value);
        end

        % Value changed function: emdTargetComp
        function emdTargetCompValueChanged(app, event)
            value = app.emdTargetComp.Value;
            app.circadian.parameter.target = value;
        end

        % Value changed function: WakethreDropDown
        function WakethreDropDownValueChanged(app, event)
            value = app.WakethreDropDown.Value;
            switch value
                case 'Low'
                    app.sleep.parameter.modeParameter.T = 20;
                case 'Medium'
                    app.sleep.parameter.modeParameter.T = 40;
                case 'High'
                    app.sleep.parameter.modeParameter.T = 80;
            end
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            value = app.OptionDropDown.Value;
            app.sleep.parameter.option = value;

            % update detection window and .sleep.parameter
            % note that these can still be changed if desired
            switch value
                case 'Nocturnal'
                    app.SleepStartEditField.Value = '21:00';
                    app.sleep.parameter.startTime = '21:00';

                    app.SleepEndEditField.Value   = '07:00';
                    app.sleep.parameter.endTime   = '07:00';
                case 'Nap 5min+'
                    app.SleepStartEditField.Value = '09:00';
                    app.sleep.parameter.startTime = '09:00';

                    app.SleepEndEditField.Value   = '19:00';
                    app.sleep.parameter.endTime   = '19:00';
                case '24 h'
                    app.SleepStartEditField.Value = '00:00';
                    app.sleep.parameter.startTime = '00:00';

                    app.SleepEndEditField.Value   = '24:00';
                    app.sleep.parameter.endTime   = '24:00';
            end

        end

        % Value changed function: TimeColumnDropDown
        function TimeColumnDropDownValueChanged(app, event)
            value = app.TimeColumnDropDown.Value;
            app.raw.Time = value;
        end

        % Value changed function: XColumnDropDown
        function XColumnDropDownValueChanged(app, event)
            value = app.XColumnDropDown.Value;
            app.raw.X = value;
        end

        % Value changed function: YColumnDropDown
        function YColumnDropDownValueChanged(app, event)
            value = app.YColumnDropDown.Value;
            app.raw.Y = value;
        end

        % Value changed function: ZColumnDropDown
        function ZColumnDropDownValueChanged(app, event)
            value = app.ZColumnDropDown.Value;
            app.raw.Z = value;
        end

        % Value changed function: StartEditField_2
        function StartEditField_2ValueChanged(app, event)
            value = app.StartEditField_2.Value;
            app.raw.StartTime = value;
        end

        % Value changed function: ApplytoAllFilesCheckBox
        function ApplytoAllFilesCheckBoxValueChanged(app, event)
            value = app.ApplytoAllFilesCheckBox.Value;
            app.raw.Apply2All = value;

            switch value
                case 1
                    app.resetRawControl('off');
                case 0
                    app.resetRawControl('on');
            end
        end

        % Value changed function: EndEditField_2
        function EndEditField_2ValueChanged(app, event)
            value = app.EndEditField_2.Value;
            app.raw.EndTime = value;
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            app.raw.Unit = value;
        end

        % Value changed function: EpochEditField
        function EpochEditFieldValueChanged(app, event)
            value = app.EpochEditField.Value;
            app.raw.Epoch = str2double(value);
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
            value = app.FsEditField.Value;
            app.raw.Fs = str2double(value);
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            called = ImportWizard3(event.Source.Text, app.config, app.fileList);

            app.statusFeed('Configuring advanced settings for raw data processing ...');
            uiwait(called.WizardFigure)
            if called.imported
                app.Status.Text  = 'Advanced raw data processing configuration done.';
                app.config = called.configStruct;
            else
                app.statusFeed('Nothing changed!');
            end

            called.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActigraphycontrolpanelUIFigure = uifigure('Visible', 'off');
            app.ActigraphycontrolpanelUIFigure.Name = 'Actigraphy: control panel';
            app.ActigraphycontrolpanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActigraphycontrolpanelUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.FileMenu.Text = '&File';
            app.ImportWizardMenu = uimenu(app.FileMenu);
            app.ImportWizardMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportWizardMenuSelected, true);
            app.ImportWizardMenu.Separator = 'on';
            app.ImportWizardMenu.Accelerator = 'I';
            app.ImportWizardMenu.Text = '&Import Wizard';
            app.OptionMenu = uimenu(app.FileMenu);
            app.OptionMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionMenuSelected, true);
            app.OptionMenu.Separator = 'on';
            app.OptionMenu.Accelerator = 'P';
            app.OptionMenu.Text = '&Option';
            app.GraphMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.GraphMenu.Text = '&Graph';
            app.ActogramMenu = uimenu(app.GraphMenu);
            app.ActogramMenu.MenuSelectedFcn = createCallbackFcn(app, @ActogramMenuSelected, true);
            app.ActogramMenu.Accelerator = 'G';
            app.ActogramMenu.Text = 'Actogram';
            app.PolarPlotMenu = uimenu(app.GraphMenu);
            app.PolarPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PolarPlotMenuSelected, true);
            app.PolarPlotMenu.Enable = 'off';
            app.PolarPlotMenu.Separator = 'on';
            app.PolarPlotMenu.Accelerator = 'R';
            app.PolarPlotMenu.Text = 'Polar Plot';
            app.ISMenu = uimenu(app.GraphMenu);
            app.ISMenu.MenuSelectedFcn = createCallbackFcn(app, @ISMenuSelected, true);
            app.ISMenu.Enable = 'off';
            app.ISMenu.Accelerator = 'B';
            app.ISMenu.Text = 'IS';
            app.AdvancedMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.AdvancedMenu.Text = 'Advanced';
            app.DetrendedfluctuationanalysisDFAMenu = uimenu(app.AdvancedMenu);
            app.DetrendedfluctuationanalysisDFAMenu.MenuSelectedFcn = createCallbackFcn(app, @DetrendedfluctuationanalysisDFAMenuSelected, true);
            app.DetrendedfluctuationanalysisDFAMenu.Accelerator = 'D';
            app.DetrendedfluctuationanalysisDFAMenu.Text = 'Detrended fluctuation analysis (DFA)';
            app.HelpMenu = uimenu(app.ActigraphycontrolpanelUIFigure);
            app.HelpMenu.Text = '&Help';
            app.AboutezActiMenu = uimenu(app.HelpMenu);
            app.AboutezActiMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutezActiMenuSelected, true);
            app.AboutezActiMenu.Text = 'About ezActi';
            app.GridLayout = uigridlayout(app.ActigraphycontrolpanelUIFigure);
            app.AutoButton = uibutton(app.GridLayout, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.FlipButton = uibutton(app.GridLayout, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Icon = 'flip.png';
            app.FlipButton.Text = '';
            app.InterpButton = uibutton(app.GridLayout, 'push');
            app.InterpButton.ButtonPushedFcn = createCallbackFcn(app, @InterpButtonPushed, true);
            app.InterpButton.Icon = 'interp.png';
            app.InterpButton.Text = '';
            app.CropButton = uibutton(app.GridLayout, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Icon = 'crop.png';
            app.CropButton.Text = '';
            app.WriteButton = uibutton(app.GridLayout, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Icon = 'write.png';
            app.WriteButton.Text = '';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Icon = 'eraser.png';
            app.ClearButton.Text = '';
            app.DoButton = uibutton(app.GridLayout, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Icon = 'define.png';
            app.DoButton.Text = '';
            app.previewUITable = uitable(app.GridLayout);
            app.previewUITable.ColumnName = {'Start'; 'End'};
            app.previewUITable.RowName = {};
            app.previewUITable.ColumnEditable = true;
            app.previewUITable.CellEditCallback = createCallbackFcn(app, @previewUITableCellEdit, true);
            app.previewUITable.CellSelectionCallback = createCallbackFcn(app, @previewUITableCellSelection, true);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Status = uilabel(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GapTab = uitab(app.TabGroup);
            app.GapTab.Title = 'Gap';
            app.GridLayout5 = uigridlayout(app.GapTab);
            app.minLabel_2 = uilabel(app.GridLayout5);
            app.minLabel_2.Enable = 'off';
            app.minLabel_2.Text = 'min';
            app.minLabel = uilabel(app.GridLayout5);
            app.minLabel.Enable = 'off';
            app.minLabel.Text = 'min';
            app.ManAutoSwitch = uiswitch(app.GridLayout5, 'slider');
            app.ManAutoSwitch.Items = {'Manual', 'Auto'};
            app.ManAutoSwitch.ValueChangedFcn = createCallbackFcn(app, @ManAutoSwitchValueChanged, true);
            app.ManAutoSwitch.Value = 'Manual';
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = '';
            app.StartEditField = uieditfield(app.GridLayout5, 'text');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.GridLayout5);
            app.StartEditFieldLabel.Text = 'Start:';
            app.EndEditField = uieditfield(app.GridLayout5, 'text');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.GridLayout5);
            app.EndEditFieldLabel.Text = 'End:';
            app.ThreEditField = uieditfield(app.GridLayout5, 'text');
            app.ThreEditField.ValueChangedFcn = createCallbackFcn(app, @ThreEditFieldValueChanged, true);
            app.ThreEditField.Enable = 'off';
            app.ThreEditFieldLabel = uilabel(app.GridLayout5);
            app.ThreEditFieldLabel.Enable = 'off';
            app.ThreEditFieldLabel.Text = 'Thre.:';
            app.MinDurEditField = uieditfield(app.GridLayout5, 'text');
            app.MinDurEditField.ValueChangedFcn = createCallbackFcn(app, @MinDurEditFieldValueChanged, true);
            app.MinDurEditField.Enable = 'off';
            app.MinDurEditFieldLabel = uilabel(app.GridLayout5);
            app.MinDurEditFieldLabel.Enable = 'off';
            app.MinDurEditFieldLabel.Text = 'Min. Dur.:';
            app.threUnitDropDown = uidropdown(app.GridLayout5);
            app.threUnitDropDown.Items = {'Min', 'Max', 'Range', 'SD'};
            app.threUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @threUnitDropDownValueChanged, true);
            app.threUnitDropDown.Enable = 'off';
            app.threUnitDropDown.Value = 'Min';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '';
            app.MergeifEditField = uieditfield(app.GridLayout5, 'text');
            app.MergeifEditField.ValueChangedFcn = createCallbackFcn(app, @MergeifEditFieldValueChanged, true);
            app.MergeifEditField.Enable = 'off';
            app.MergeifEditFieldLabel = uilabel(app.GridLayout5);
            app.MergeifEditFieldLabel.Enable = 'off';
            app.MergeifEditFieldLabel.Text = 'Merge if <:';
            app.CircadianTab = uitab(app.TabGroup);
            app.CircadianTab.Title = 'Circadian';
            app.GridLayout6 = uigridlayout(app.CircadianTab);
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.CosinorTab = uitab(app.TabGroup2);
            app.CosinorTab.Title = 'Cosinor';
            app.GridLayout2 = uigridlayout(app.CosinorTab);
            app.hourLabel_3 = uilabel(app.GridLayout2);
            app.hourLabel_3.Text = 'hour';
            app.hourLabel_2 = uilabel(app.GridLayout2);
            app.hourLabel_2.Text = 'hour';
            app.hourLabel = uilabel(app.GridLayout2);
            app.hourLabel.Text = 'hour';
            app.TargetcomponentEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TargetcomponentEditField.ValueChangedFcn = createCallbackFcn(app, @TargetcomponentEditFieldValueChanged, true);
            app.TargetcomponentEditField.Value = 24;
            app.TargetcomponentLabel = uilabel(app.GridLayout2);
            app.TargetcomponentLabel.Text = 'Target component:';
            app.HarmonicsEditField = uieditfield(app.GridLayout2, 'text');
            app.HarmonicsEditField.ValueChangedFcn = createCallbackFcn(app, @HarmonicsEditFieldValueChanged, true);
            app.HarmonicsLabel = uilabel(app.GridLayout2);
            app.HarmonicsLabel.Text = 'Harmonics:';
            app.MinlengthEditField = uieditfield(app.GridLayout2, 'text');
            app.MinlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinlengthEditFieldValueChanged, true);
            app.MinlengthEditField.Value = '7*24';
            app.MinlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MinlengthEditFieldLabel.Text = 'Min. length:';
            app.CIalphaEditFieldLabel = uilabel(app.GridLayout2);
            app.CIalphaEditFieldLabel.Text = 'CI alpha:';
            app.CIalphaEditField = uieditfield(app.GridLayout2, 'text');
            app.CIalphaEditField.ValueChangedFcn = createCallbackFcn(app, @CIalphaEditFieldValueChanged, true);
            app.CIalphaEditField.Value = '0.05';
            app.NPTab = uitab(app.TabGroup2);
            app.NPTab.Title = 'NP';
            app.GridLayout7 = uigridlayout(app.NPTab);
            app.FixLenghEditFieldLabel = uilabel(app.GridLayout7);
            app.FixLenghEditFieldLabel.Text = 'Fix Lengh:';
            app.FixLenghEditField = uieditfield(app.GridLayout7, 'numeric');
            app.FixLenghEditField.Limits = [2 Inf];
            app.FixLenghEditField.ValueChangedFcn = createCallbackFcn(app, @FixLenghEditFieldValueChanged, true);
            app.FixLenghEditField.Value = 5;
            app.dayLabel = uilabel(app.GridLayout7);
            app.dayLabel.Text = 'day';
            app.ScaleEditFieldLabel = uilabel(app.GridLayout7);
            app.ScaleEditFieldLabel.Text = 'Scale:';
            app.ScaleEditField = uieditfield(app.GridLayout7, 'text');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = '60';
            app.minLabel_3 = uilabel(app.GridLayout7);
            app.minLabel_3.Text = 'min';
            app.PeriodEditFieldLabel = uilabel(app.GridLayout7);
            app.PeriodEditFieldLabel.Text = 'Period:';
            app.PeriodEditField = uieditfield(app.GridLayout7, 'text');
            app.PeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodEditFieldValueChanged, true);
            app.PeriodEditField.Value = '24';
            app.hourLabel_4 = uilabel(app.GridLayout7);
            app.hourLabel_4.Text = 'hour';
            app.EMDTab = uitab(app.TabGroup2);
            app.EMDTab.Title = 'EMD';
            app.GridLayout8 = uigridlayout(app.EMDTab);
            app.hourLabel_5 = uilabel(app.GridLayout8);
            app.hourLabel_5.Text = 'hour';
            app.hourLabel_6 = uilabel(app.GridLayout8);
            app.hourLabel_6.Text = 'hour';
            app.emdMinLen = uieditfield(app.GridLayout8, 'text');
            app.emdMinLen.ValueChangedFcn = createCallbackFcn(app, @emdMinLenValueChanged, true);
            app.emdMinLen.Value = '7*24';
            app.emdTargetComp = uieditfield(app.GridLayout8, 'numeric');
            app.emdTargetComp.ValueChangedFcn = createCallbackFcn(app, @emdTargetCompValueChanged, true);
            app.emdTargetComp.Value = 24;
            app.MinlengthLabel = uilabel(app.GridLayout8);
            app.MinlengthLabel.Text = 'Min. length:';
            app.TargetcomponentLabel_2 = uilabel(app.GridLayout8);
            app.TargetcomponentLabel_2.Text = 'Target component:';
            app.SleepTab = uitab(app.TabGroup);
            app.SleepTab.Title = 'Sleep';
            app.GridLayout4 = uigridlayout(app.SleepTab);
            app.ModeDropDown_Sleep = uidropdown(app.GridLayout4);
            app.ModeDropDown_Sleep.Items = {'Cole-Kripke', 'Actiware'};
            app.ModeDropDown_Sleep.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_SleepValueChanged, true);
            app.ModeDropDown_Sleep.Value = 'Cole-Kripke';
            app.AlgorithmLabel = uilabel(app.GridLayout4);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.SleepStartEditField = uieditfield(app.GridLayout4, 'text');
            app.SleepStartEditField.ValueChangedFcn = createCallbackFcn(app, @SleepStartEditFieldValueChanged, true);
            app.SleepStartEditField.Tooltip = {'Use 00:00--24:00 for 24-hour sleep detection'};
            app.SleepStartEditField.Value = '21:00';
            app.TimerestrictionLabel = uilabel(app.GridLayout4);
            app.TimerestrictionLabel.Text = 'Time window:';
            app.SleepEndEditField = uieditfield(app.GridLayout4, 'text');
            app.SleepEndEditField.ValueChangedFcn = createCallbackFcn(app, @SleepEndEditFieldValueChanged, true);
            app.SleepEndEditField.Tooltip = {'Use 00:00--24:00 for 24-hour sleep detection'};
            app.SleepEndEditField.Value = '7:00';
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = '--';
            app.WakethreDropDownLabel = uilabel(app.GridLayout4);
            app.WakethreDropDownLabel.Visible = 'off';
            app.WakethreDropDownLabel.Text = 'Wake thre.:';
            app.WakethreDropDown = uidropdown(app.GridLayout4);
            app.WakethreDropDown.Items = {'Low', 'Medium', 'High'};
            app.WakethreDropDown.ValueChangedFcn = createCallbackFcn(app, @WakethreDropDownValueChanged, true);
            app.WakethreDropDown.Visible = 'off';
            app.WakethreDropDown.Value = 'Medium';
            app.OptionDropDownLabel = uilabel(app.GridLayout4);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.GridLayout4);
            app.OptionDropDown.Items = {'Nocturnal', 'Nap 5min+', '24 h'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.OptionDropDown.Value = 'Nocturnal';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.circShowArea = uitextarea(app.GridLayout);
            app.circShowArea.Visible = 'off';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '';
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '';
            app.TextArea = uitextarea(app.GridLayout);
            app.AccelerometertoActivityCountsPanel = uipanel(app.GridLayout);
            app.AccelerometertoActivityCountsPanel.Title = 'Accelerometer to Activity Counts';
            app.GridLayout9 = uigridlayout(app.AccelerometertoActivityCountsPanel);
            app.TimeColumnDropDownLabel = uilabel(app.GridLayout9);
            app.TimeColumnDropDownLabel.Text = 'Time Column:';
            app.TimeColumnDropDown = uidropdown(app.GridLayout9);
            app.TimeColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeColumnDropDownValueChanged, true);
            app.XColumnDropDownLabel = uilabel(app.GridLayout9);
            app.XColumnDropDownLabel.Text = 'X Column:';
            app.XColumnDropDown = uidropdown(app.GridLayout9);
            app.XColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @XColumnDropDownValueChanged, true);
            app.YColumnDropDownLabel = uilabel(app.GridLayout9);
            app.YColumnDropDownLabel.Text = 'Y Column:';
            app.YColumnDropDown = uidropdown(app.GridLayout9);
            app.YColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @YColumnDropDownValueChanged, true);
            app.ZColumnDropDownLabel = uilabel(app.GridLayout9);
            app.ZColumnDropDownLabel.Text = 'Z Column:';
            app.ZColumnDropDown = uidropdown(app.GridLayout9);
            app.ZColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ZColumnDropDownValueChanged, true);
            app.StartEditField_2Label = uilabel(app.GridLayout9);
            app.StartEditField_2Label.Text = 'Start:';
            app.StartEditField_2 = uieditfield(app.GridLayout9, 'text');
            app.StartEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartEditField_2ValueChanged, true);
            app.StartEditField_2.Tooltip = {'Desired start time to convert data (in HH:mm format)'};
            app.StartEditField_2.Value = '19:00';
            app.ApplytoAllFilesCheckBox = uicheckbox(app.GridLayout9);
            app.ApplytoAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplytoAllFilesCheckBoxValueChanged, true);
            app.ApplytoAllFilesCheckBox.Text = 'Apply to All Files';
            app.EndEditField_2Label = uilabel(app.GridLayout9);
            app.EndEditField_2Label.Text = 'End:';
            app.EndEditField_2 = uieditfield(app.GridLayout9, 'text');
            app.EndEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndEditField_2ValueChanged, true);
            app.EndEditField_2.Tooltip = {'Desired end time to convert data (in HH:mm format)'};
            app.EndEditField_2.Value = '06:00';
            app.UnitDropDownLabel = uilabel(app.GridLayout9);
            app.UnitDropDownLabel.Text = 'Unit:';
            app.UnitDropDown = uidropdown(app.GridLayout9);
            app.UnitDropDown.Items = {'milli-g', 'g'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'milli-g';
            app.EpochEditFieldLabel = uilabel(app.GridLayout9);
            app.EpochEditFieldLabel.Text = 'Epoch:';
            app.EpochEditField = uieditfield(app.GridLayout9, 'text');
            app.EpochEditField.ValueChangedFcn = createCallbackFcn(app, @EpochEditFieldValueChanged, true);
            app.EpochEditField.Tooltip = {'Epoch lengh for activity count (in second)'};
            app.EpochEditField.Value = '30';
            app.FsEditFieldLabel = uilabel(app.GridLayout9);
            app.FsEditFieldLabel.Text = 'Fs:';
            app.FsEditField = uieditfield(app.GridLayout9, 'text');
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.FsEditField.Tooltip = {'Sampling frequency in Hz'};
            app.FsEditField.Value = '25';
            app.AdvancedButton = uibutton(app.GridLayout9, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            % Show the figure after all components are created
            app.ActigraphycontrolpanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ezActi3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ActigraphycontrolpanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActigraphycontrolpanelUIFigure)
        end
    end
end